classdef cbarCreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CreatecolormapTab               matlab.ui.container.Tab
        IncludemiddlecolorininterpolationLabel  matlab.ui.control.Label
        includeMid                      matlab.ui.control.Switch
        b                               matlab.ui.control.Slider
        BSliderLabel                    matlab.ui.control.Label
        g                               matlab.ui.control.Slider
        GSliderLabel                    matlab.ui.control.Label
        r                               matlab.ui.control.Slider
        RSliderLabel                    matlab.ui.control.Label
        bBox                            matlab.ui.control.NumericEditField
        gBox                            matlab.ui.control.NumericEditField
        rBox                            matlab.ui.control.NumericEditField
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ChangerightmostcoloronbarButton  matlab.ui.control.RadioButton
        ChangemiddlecoloronbarButton    matlab.ui.control.RadioButton
        ChangeleftmostcoloronbarButton  matlab.ui.control.RadioButton
        binBox                          matlab.ui.control.NumericEditField
        BinsEditFieldLabel              matlab.ui.control.Label
        InvertButton                    matlab.ui.control.Button
        colormapSelector                matlab.ui.control.DropDown
        box                             matlab.ui.control.UIAxes
        updatecoloronbarButton          matlab.ui.control.Button
        CombineexistingcolormapsTab     matlab.ui.container.Tab
        SelectcolormaponrightsideofbarPanel  matlab.ui.container.Panel
        rightInvert                     matlab.ui.control.Button
        rightBinBox                     matlab.ui.control.NumericEditField
        BinsEditFieldLabel_3            matlab.ui.control.Label
        rightColormapSelector           matlab.ui.control.DropDown
        SelectcolormaponleftsideofbarPanel  matlab.ui.container.Panel
        leftInvert                      matlab.ui.control.Button
        leftBinBox                      matlab.ui.control.NumericEditField
        BinsEditFieldLabel_2            matlab.ui.control.Label
        leftColormapSelector            matlab.ui.control.DropDown
        saveAndButton                   matlab.ui.control.Button
        savecolormapButton              matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        bar                             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %os = []; % Description
        CallingApp
    end
    
    properties (Access = private)
        actionType = []; % Description
        props = [];
        im = [];
        im2 = [];
        cMap = [];
        cMap2 = [];
        bins = [];
        tog = [];
        hlf1 = [];
        hlf2 = [];
    end
    
    methods (Access = private)
        
        function [app] = cmapget(app,barpart)
            if strcmpi(barpart,'whole')
                nb = app.bins;
                selectorName = 'colormapSelector';
            elseif strcmpi(barpart,'left')
                nb = app.hlf1;
                selectorName = 'leftColormapSelector';
            elseif strcmpi(barpart,'right')
                nb = app.hlf2;
                selectorName = 'rightColormapSelector';
            end
            
            if ~strcmpi(app.(selectorName).Value,app.(selectorName).Items{1})
                cV = find(ismember(app.(selectorName).Items,app.(selectorName).Value));

                if app.CallingApp.userColor{cV} == 1
                    switch app.(selectorName).Value
                        case 'jet'
                            cMapTmp = jet(nb);
                        case 'parula'
                            cMapTmp = parula(nb);
                        case 'hsv'
                            cMapTmp = hsv(nb);
                        case 'hot'
                            cMapTmp = hot(nb);
                        case 'cool'
                            cMapTmp = cool(nb);
                        case 'spring'
                            cMapTmp = spring(nb);
                        case 'summer'
                            cMapTmp = summer(nb);
                        case 'autumn'
                            cMapTmp = autumn(nb);
                        case 'winter'
                            cMapTmp = winter(nb);
                        case 'gray'
                            cMapTmp = gray(nb);
                        case 'bone'
                            cMapTmp = bone(nb);
                        case 'copper'
                            cMapTmp = copper(nb);
                        case 'pink'
                            cMapTmp = pink(nb);
                        case 'lines'
                            cMapTmp = lines(nb);
                        case 'colorcube'
                            cMapTmp = colorcube(nb);
                        case 'prism'
                            cMapTmp = prism(nb);
                        case 'Spectral'
                            cMapTmp = cbrewer('div', app.(selectorName).Value, nb);
                        case 'RdYlBu'
                            cMapTmp = cbrewer('div', app.(selectorName).Value, nb);
                        case 'RdGy'
                            cMapTmp = cbrewer('div', app.(selectorName).Value, nb);
                        case 'RdBu'
                            cMapTmp = cbrewer('div', app.(selectorName).Value, nb);
                        case 'PuOr'
                            cMapTmp = cbrewer('div', app.(selectorName).Value, nb);
                        case 'PRGn'
                            cMapTmp = cbrewer('div', app.(selectorName).Value, nb);
                        case 'PiYG'
                            cMapTmp = cbrewer('div', app.(selectorName).Value, nb);
                        case 'BrBG'
                            cMapTmp = cbrewer('div', app.(selectorName).Value, nb);
                        case 'YlOrRd'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'YlOrBr'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'YlGnBu'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'YlGn'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'Reds'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'RdPu'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'Purples'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'PuRd'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'PuBuGn'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'PuBu'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'OrRd'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'Oranges'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'Greys'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'Greens'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'GnBu'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'BuPu'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'BuGn'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'Blues'
                            cMapTmp = cbrewer('seq', app.(selectorName).Value, nb);
                        case 'Set3'
                            cMapTmp = cbrewer('qual', app.(selectorName).Value, nb);
                        case 'Set2'
                            cMapTmp = cbrewer('qual', app.(selectorName).Value, nb);
                        case 'Set1'
                            cMapTmp = cbrewer('qual', app.(selectorName).Value, nb);
                        case 'Pastel2'
                            cMapTmp = cbrewer('qual', app.(selectorName).Value, nb);
                        case 'Pastel1'
                            cMapTmp = cbrewer('qual', app.(selectorName).Value, nb);
                        case 'Paired'
                            cMapTmp = cbrewer('qual', app.(selectorName).Value, nb);
                        case 'Dark2'
                            cMapTmp = cbrewer('qual', app.(selectorName).Value, nb);
                        case 'Accent'
                            cMapTmp = cbrewer('qual', app.(selectorName).Value, nb);
                        case 'inferno'
                            cMapTmp = inferno(nb);
                        case 'plasma'
                            cMapTmp = plasma(nb);
                        case 'vega10'
                            cMapTmp = vega10(nb);
                        case 'vega20b'
                            cMapTmp = vega20b(nb);
                        case 'vega20c'
                            cMapTmp = vega20c(nb);
                        case 'viridis'
                            cMapTmp = viridis(nb);
                        case 'thermal'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'haline'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'solar'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'ice'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'oxy'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'deep'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'dense'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'algae'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'matter'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'turbid'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'speed'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'amp'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'tempo'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'balance'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'delta'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'curl'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'phase'
                            cMapTmp = cmocean(app.(selectorName).Value, nb);
                        case 'perceptually distinct'
                            cMapTmp = distinguishable_colors(nb);
                    end
                else
                    cMapTmp = app.CallingApp.userColor{cV};
                    if ~strcmpi(barpart,'whole')
                        ends = customColorMapInterp([cMapTmp(1,:) ; cMapTmp(end,:)],size(cMapTmp,1));
                        mdpt = ends(round(size(ends,1)/2),:);
                        if isequal(cMapTmp(round(size(ends,1)/2),1),mdpt(1)) & isequal(cMapTmp(round(size(ends,1)/2),2),mdpt(2)) & isequal(cMapTmp(round(size(ends,1)/2),3),mdpt(3))
                            cMapTmp = customColorMapInterp([cMapTmp(1,:) ; cMapTmp(end,:)],nb);
                        else
                            cMap1 = customColorMapInterp([cMapTmp(1,:) ; cMapTmp(round(size(ends,1)/2),:)],round(nb/2));
                            cMap2 = customColorMapInterp([cMapTmp(round(size(ends,1)/2),:) ; cMapTmp(end,:)],nb - round(nb/2));
                            cMapTmp = [cMap1; cMap2];
                        end
                    end
                end

            else
                cMapTmp = ones(nb,3);
            end
            
            app.cMap = squeeze(app.cMap);
            if strcmpi(barpart,'whole')
                app.cMap = cMapTmp;
            elseif strcmpi(barpart,'left')
                app.cMap(1:app.hlf1,:) = cMapTmp;
            elseif strcmpi(barpart,'right')
                app.cMap(app.hlf1+1:end,:) = cMapTmp;
            end
            
            app = updateBar(app,barpart);
        end
        
        function [app] = updateBox(app)
            app.cMap2(:,1,1) = app.rBox.Value;
            app.cMap2(:,1,2) = app.gBox.Value;
            app.cMap2(:,1,3) = app.bBox.Value;
            app.im2 = image(app.cMap2,'Parent',app.box,'XData', [1 app.box.Position(3)],'YData', [1 app.box.Position(4)]); 
        end
        
        function [app] = updateBar(app,barpart)
            app.cMap = reshape(app.cMap,[size(app.cMap,1),1,3]);
            app.cMap = imrotate(app.cMap,90);
            app.im = image(app.cMap,'Parent',app.bar,'XData', [1 app.bar.Position(3)],'YData', [1 app.bar.Position(4)]);
        end
        
        function [app] = radioBarUpdateBox(app)
            cMapTmp = squeeze(app.cMap);
            switch app.ButtonGroup.SelectedObject.Text
                case 'Change leftmost color on bar'
                    app.rBox.Value = cMapTmp(1,1);
                    app.gBox.Value = cMapTmp(1,2);
                    app.bBox.Value = cMapTmp(1,3);
                case 'Change rightmost color on bar'
                    app.rBox.Value = cMapTmp(end,1);
                    app.gBox.Value = cMapTmp(end,2);
                    app.bBox.Value = cMapTmp(end,3);
                case 'Change middle color on bar'
                    app.rBox.Value = cMapTmp(ceil(length(cMapTmp)/2),1);
                    app.gBox.Value = cMapTmp(ceil(length(cMapTmp)/2),2);
                    app.bBox.Value = cMapTmp(ceil(length(cMapTmp)/2),3);
            end
            app.r.Value = app.rBox.Value;
            app.g.Value = app.gBox.Value;
            app.b.Value = app.bBox.Value;

            [app] = updateBox(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, labelAdd)
            app.CallingApp = mainapp;
            app.colormapSelector.Items = app.CallingApp.colormapSelector.Items;
            app.rightColormapSelector.Items = app.CallingApp.colormapSelector.Items;
            app.leftColormapSelector.Items = app.CallingApp.colormapSelector.Items;
            app.colormapSelector.Value = app.CallingApp.colormapSelector.Value;
            
            [s,~,si] = intersect(app.CallingApp.overlayListBox.Value,app.CallingApp.overlayListBox.Items);

            if ~isempty(si)
                if si ~= 1
                    app.bins = app.CallingApp.overlay.settings{si}.binBox;
                else
                    app.bins = 1000;
                end
            else
                app.bins = 1000;
            end
            app.binBox.Value = app.bins;
            app.hlf1 = round(app.bins/2);
            app.hlf2 = app.bins - app.hlf1;
            
            [app] = cmapget(app,'whole');
            
            app.cMap2 = zeros(2,1,3);
            [app] = updateBox(app);        
        end

        % Button pushed function: savecolormapButton
        function saveColorButtonPress(app, event)
            customColor = squeeze(app.cMap);
            
            if size(customColor,1) < app.bins
                diff = app.bins - size(customColor,1);
                customColor = [customColor(1:app.hlf1-1,:); repmat(customColor(app.hlf1,:),diff+1,1) ; customColor(app.hlf1+1:end,:)];
            end
            
            [oFile, oPath] = uiputfile({'*.mat'},'Pick a name for your colormap',[app.CallingApp.pths.clrmpPth app.CallingApp.pths.sep 'colormap']);
            save([oPath oFile]','customColor');
            app.CallingApp.colormapSelector.Items = [app.CallingApp.colormapSelector.Items oFile(1:end-4)];
            app.CallingApp.colormapSelectorSurf.Items = [app.CallingApp.colormapSelectorSurf.Items oFile(1:end-4)];
            app.CallingApp.colormapSelectAtlas.Items = [app.CallingApp.colormapSelectAtlas.Items oFile(1:end-4)];
            app.CallingApp.userColor = [app.CallingApp.userColor customColor];
            
            app.colormapSelector.Items = app.CallingApp.colormapSelector.Items;
            app.rightColormapSelector.Items = app.CallingApp.colormapSelector.Items;
            app.leftColormapSelector.Items = app.CallingApp.colormapSelector.Items;
        end

        % Close request function: UIFigure
        function oSelectCloseRequest(app, event)
            delete(app)
        end

        % Selection changed function: ButtonGroup
        function rgbSelection(app, event)
            [app] = radioBarUpdateBox(app);
        end

        % Value changed function: r
        function rSlider(app, event)
            %value = app.r.Value;
            app.rBox.Value = app.r.Value;
            app.cMap2(:,1) = app.rBox.Value;
            app.cMap2(:,2) = app.gBox.Value;
            app.cMap2(:,3) = app.bBox.Value;
            
            [app] = updateBox(app); 
        end

        % Value changed function: g
        function gSlider(app, event)
            app.gBox.Value = app.g.Value;
            app.cMap2(:,1) = app.rBox.Value;
            app.cMap2(:,2) = app.gBox.Value;
            app.cMap2(:,3) = app.bBox.Value;
            
            [app] = updateBox(app); 
        end

        % Value changed function: b
        function bSliders(app, event)
            app.bBox.Value = app.b.Value;
            app.cMap2(:,1) = app.rBox.Value;
            app.cMap2(:,2) = app.gBox.Value;
            app.cMap2(:,3) = app.bBox.Value;
            
            [app] = updateBox(app); 
        end

        % Value changed function: rBox
        function rBoxChange(app, event)
            app.r.Value = app.rBox.Value;
            app.cMap2(:,1) = app.rBox.Value;
            app.cMap2(:,2) = app.gBox.Value;
            app.cMap2(:,3) = app.bBox.Value;
            
            [app] = updateBox(app); 
        end

        % Value changed function: gBox
        function gBoxChange(app, event)
            app.g.Value = app.gBox.Value;
            app.cMap2(:,1) = app.rBox.Value;
            app.cMap2(:,2) = app.gBox.Value;
            app.cMap2(:,3) = app.bBox.Value;
            
            [app] = updateBox(app); 
        end

        % Value changed function: bBox
        function bBoxChange(app, event)
            app.b.Value = app.bBox.Value;
            app.cMap2(:,1) = app.rBox.Value;
            app.cMap2(:,2) = app.gBox.Value;
            app.cMap2(:,3) = app.bBox.Value;
            
            [app] = updateBox(app); 
        end

        % Value changed function: includeMid
        function inclMidButton(app, event)
            cMapTmp = squeeze(app.cMap);
            if strcmpi(app.includeMid.Value,'Yes')
                tmp1 = customColorMapInterp([cMapTmp(1,1) cMapTmp(1,2) cMapTmp(1,3); cMapTmp(app.hlf1,1) cMapTmp(app.hlf1,2) cMapTmp(app.hlf1,3)],app.hlf1);
                tmp2 = customColorMapInterp([cMapTmp(app.hlf2,1) cMapTmp(app.hlf2,2) cMapTmp(app.hlf2,3); cMapTmp(end,1) cMapTmp(end,2) cMapTmp(end,3)],app.hlf2);
                app.cMap = vertcat(tmp1(1:end-1,:),tmp2);
            else
                app.cMap = customColorMapInterp([cMapTmp(1,1) cMapTmp(1,2) cMapTmp(1,3); cMapTmp(end,1) cMapTmp(end,2) cMapTmp(end,3)],app.bins);
            end
            app = updateBar(app);
            [app] = radioBarUpdateBox(app);
        end

        % Button pushed function: updatecoloronbarButton
        function updateBoxButton(app, event)
            cMapTmp = squeeze(app.cMap);            
            switch app.ButtonGroup.SelectedObject.Text
                case 'Change leftmost color on bar'
                    if strcmpi(app.includeMid.Value,'No')
                        app.cMap = customColorMapInterp([app.rBox.Value app.gBox.Value app.bBox.Value; cMapTmp(end,1) cMapTmp(end,2) cMapTmp(end,3)],app.bins);
                    else
                        tmp1 = customColorMapInterp([app.rBox.Value app.gBox.Value app.bBox.Value; cMapTmp(ceil(length(cMapTmp)/2),1) cMapTmp(ceil(length(app.cMap)/2),2) cMapTmp(ceil(length(app.cMap)/2),3)],app.hlf1);
                        tmp2 = customColorMapInterp([cMapTmp(ceil(length(cMapTmp)/2),1) cMapTmp(ceil(length(cMapTmp)/2),2) cMapTmp(ceil(length(cMapTmp)/2),3); cMapTmp(end,1) cMapTmp(end,2) cMapTmp(end,3)],app.hlf2);
                        app.cMap = vertcat(tmp1(1:end-1,:),tmp2);
                    end
                    
                case 'Change rightmost color on bar'
                    if strcmpi(app.includeMid.Value,'No')
                       app.cMap = customColorMapInterp([cMapTmp(1,1) cMapTmp(1,2) cMapTmp(1,3); app.rBox.Value app.gBox.Value app.bBox.Value],app.bins);
                    else
                        tmp1 = customColorMapInterp([cMapTmp(1,1) cMapTmp(1,2) cMapTmp(1,3); cMapTmp(ceil(length(cMapTmp)/2),1) cMapTmp(ceil(length(cMapTmp)/2),2) cMapTmp(ceil(length(cMapTmp)/2),3)],app.hlf1);
                        tmp2 = customColorMapInterp([cMapTmp(ceil(length(cMapTmp)/2),1) cMapTmp(ceil(length(cMapTmp)/2),2) cMapTmp(ceil(length(cMapTmp)/2),3); app.rBox.Value app.gBox.Value app.bBox.Value],app.hlf2);
                        app.cMap = vertcat(tmp1(1:end-1,:),tmp2);
                    end
                    
                case 'Change middle color on bar'
                    tmp1 = customColorMapInterp([cMapTmp(1,1) cMapTmp(1,2) cMapTmp(1,3); app.rBox.Value app.gBox.Value app.bBox.Value],app.hlf1);
                    tmp2 = customColorMapInterp([app.rBox.Value app.gBox.Value app.bBox.Value; cMapTmp(end,1) cMapTmp(end,2) cMapTmp(end,3)],app.hlf2);
                    app.cMap = vertcat(tmp1(1:end-1,:),tmp2);
                    if strcmpi(app.includeMid.Value,'No')
                        app.includeMid.Value = 'Yes';
                    end
            end
            app = updateBar(app);
        end

        % Button pushed function: saveAndButton
        function saveApplyButtonPress(app, event)
            customColor = squeeze(app.cMap);
            if size(customColor,1) < app.bins
               diff = app.bins - size(customColor,1);
               customColor = [customColor(1:app.hlf1-1,:); repmat(customColor(app.hlf1,:),diff+1,1) ; customColor(app.hlf1+1:end,:)];
            end
          
            [oFile, oPath] = uiputfile({'*.mat'},'Pick a name for your colormap',[app.CallingApp.pths.clrmpPth app.CallingApp.pths.sep 'colormap']);
            save([oPath oFile]','customColor');
            app.CallingApp.colormapSelector.Items = [app.CallingApp.colormapSelector.Items oFile(1:end-4)];
            app.CallingApp.colormapSelectorSurf.Items = [app.CallingApp.colormapSelectorSurf.Items oFile(1:end-4)];
            app.CallingApp.colormapSelectAtlas.Items = [app.CallingApp.colormapSelectAtlas.Items oFile(1:end-4)];
            app.colormapSelector.Items = app.CallingApp.colormapSelector.Items;
            app.rightColormapSelector.Items = app.CallingApp.colormapSelector.Items;
            app.leftColormapSelector.Items = app.CallingApp.colormapSelector.Items;
            
            app.CallingApp.userColor = [app.CallingApp.userColor customColor];
            app.CallingApp.colormapSelector.Value = app.CallingApp.colormapSelector.Items{end};
            
            selectIndex = [];
            remIdx = 1;
            listBoxName = 'overlayListBox';
            for i = 1:length(app.CallingApp.(listBoxName).Value)
                [st,~,sit] = intersect(app.CallingApp.(listBoxName).Value(i),app.CallingApp.(listBoxName).Items);
                selectIndex = [selectIndex; sit];
            end
            id = find(selectIndex == remIdx);
            selectIndex(id) = [];
            
            for i = 1:length(selectIndex)
                app.CallingApp.overlay.settings{selectIndex(i)}.colormapSelector = app.CallingApp.colormapSelector.Items{end};
            end
            
            [~,ia,~] = intersect(app.CallingApp.overlayListBox.Items,app.CallingApp.overlayListBox.Value);
            if ia ~= 1 | ~isgraphics(app.CallingApp.brainFig,'figure')
                app.CallingApp = overPatch(app.CallingApp,'false','false','true');
                app.CallingApp.binBox.Value = app.bins;
                delete(app)
            else
                uialert(app.CallingApp.brainSurferUIFigure,'Your colormap was saved but you cannot patch a new overlay because either a surface is not loaded or an overlay is not selected.','Action unavailable');
            end
        end

        % Value changed function: colormapSelector
        function colormapSelectorChange(app, event)
            %value = app.colormapSelector.Value;
            [app] = cmapget(app,'whole');
            [app] = radioBarUpdateBox(app);
            app.includeMid.Value = 'Yes';
            app.rightColormapSelector.Value = app.rightColormapSelector.Items{1};
            app.leftColormapSelector.Value = app.leftColormapSelector.Items{1};
            
        end

        % Button pushed function: InvertButton
        function invertBar(app, event)
            app.cMap = fliplr(app.cMap);
            app.im = image(app.cMap,'Parent',app.bar,'XData', [1 app.bar.Position(3)],'YData', [1 app.bar.Position(4)]);
            
            [app] = radioBarUpdateBox(app);
        end

        % Value changed function: binBox
        function binBoxChange(app, event)
            if mod(app.binBox.Value,2)
                app.binBox.Value = app.binBox.Value+1;
            end
            
            hlf1_old = app.hlf1;
            hlf2_old = app.hlf2;
            
            app.bins = app.binBox.Value;
            app.hlf1 = round(app.bins/2);
            app.hlf2 = app.bins - app.hlf1;
            
            cMapTmp = squeeze(app.cMap);
            if strcmpi(app.includeMid.Value,'Yes')
                tmp1 = customColorMapInterp([cMapTmp(1,1) cMapTmp(1,2) cMapTmp(1,3); cMapTmp(hlf1_old,1) cMapTmp(hlf1_old,2) cMapTmp(hlf1_old,3)],app.hlf1);
                tmp2 = customColorMapInterp([cMapTmp(hlf2_old,1) cMapTmp(hlf2_old,2) cMapTmp(hlf2_old,3); cMapTmp(end,1) cMapTmp(end,2) cMapTmp(end,3)],app.hlf2);
                app.cMap = vertcat(tmp1(1:end-1,:),tmp2);
            else
                app.cMap = customColorMapInterp([cMapTmp(1,1) cMapTmp(1,2) cMapTmp(1,3); cMapTmp(end,1) cMapTmp(end,2) cMapTmp(end,3)],app.bins);
            end
            app = updateBar(app);
            
            [app] = radioBarUpdateBox(app);
        end

        % Value changed function: leftBinBox
        function leftBinBoxChange(app, event)
            %value = app.leftBinBox.Value;
            if mod(app.leftBinBox.Value,2)
                app.leftBinBox.Value = app.leftBinBox.Value+1;
            end
            
            app.hlf1 = app.leftBinBox.Value;
            app.bins = app.hlf1+app.hlf2;
            
            app.cMap = zeros([1,app.bins,3]);
            [app] = cmapget(app,'left');
            [app] = cmapget(app,'right');
            [app] = radioBarUpdateBox(app);
        end

        % Value changed function: rightBinBox
        function rightBinBoxChange(app, event)
            %value = app.rightBinBox.Value;
            if mod(app.rightBinBox.Value,2)
                app.rightBinBox.Value = app.rightBinBox.Value+1;
            end
            
            app.hlf2 = app.rightBinBox.Value;
            app.bins = app.hlf1+app.hlf2;
            
            app.cMap = zeros([1,app.bins,3]);
            [app] = cmapget(app,'left');
            [app] = cmapget(app,'right');
            [app] = radioBarUpdateBox(app);
        end

        % Button pushed function: rightInvert
        function rightInvertChange(app, event)
            app.cMap(1,app.hlf1+1:end,:) = fliplr(app.cMap(1,app.hlf1+1:end,:));
            app.im = image(app.cMap,'Parent',app.bar,'XData', [1 app.bar.Position(3)],'YData', [1 app.bar.Position(4)]);
            
            [app] = radioBarUpdateBox(app);
        end

        % Button pushed function: leftInvert
        function leftInvertChange(app, event)
            app.cMap(1,1:app.hlf1,:) = fliplr(app.cMap(1,1:app.hlf1,:));
            app.im = image(app.cMap,'Parent',app.bar,'XData', [1 app.bar.Position(3)],'YData', [1 app.bar.Position(4)]);
            
            [app] = radioBarUpdateBox(app);
        end

        % Value changed function: leftColormapSelector
        function leftColormapSelectorChange(app, event)
            %value = app.leftColormapSelector.Value;
            app.colormapSelector.Value = app.colormapSelector.Items{1};
            app.leftBinBox.Value = app.hlf1;
            
            [app] = cmapget(app,'left');
            [app] = radioBarUpdateBox(app);
        end

        % Value changed function: rightColormapSelector
        function rightColormapSelectorChange(app, event)
            %value = app.rightColormapSelector.Value;
            app.colormapSelector.Value = app.colormapSelector.Items{1};
            app.rightBinBox.Value = app.hlf2;
            
            [app] = cmapget(app,'right');
            [app] = radioBarUpdateBox(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @oSelectCloseRequest, true);
            app.bar = uiaxes(app.UIFigure);
            zlabel(app.bar, 'Z')
            app.bar.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.bar.XTickLabel = '';
            app.bar.YTickLabel = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Colormap creator';
            app.savecolormapButton = uibutton(app.UIFigure, 'push');
            app.savecolormapButton.ButtonPushedFcn = createCallbackFcn(app, @saveColorButtonPress, true);
            app.savecolormapButton.Text = 'save colormap';
            app.saveAndButton = uibutton(app.UIFigure, 'push');
            app.saveAndButton.ButtonPushedFcn = createCallbackFcn(app, @saveApplyButtonPress, true);
            app.saveAndButton.Text = 'save colormap and apply to currently selected overlay';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CreatecolormapTab = uitab(app.TabGroup);
            app.CreatecolormapTab.Title = 'Create colormap';
            app.updatecoloronbarButton = uibutton(app.CreatecolormapTab, 'push');
            app.updatecoloronbarButton.ButtonPushedFcn = createCallbackFcn(app, @updateBoxButton, true);
            app.updatecoloronbarButton.Text = {'update color'; 'on bar'};
            app.box = uiaxes(app.CreatecolormapTab);
            zlabel(app.box, 'Z')
            app.box.XTickLabel = '';
            app.box.YTickLabel = '';
            app.colormapSelector = uidropdown(app.CreatecolormapTab);
            app.colormapSelector.Items = {'Select colormap to begin editing', 'jet', 'parula', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'Spectral', 'RdYlBu', 'RdGy', 'RdBu', 'PuOr', 'PRGn', 'PiYG', 'BrBG', 'YlOrRd', 'YlOrBr', 'YlGnBu', 'YlGn', 'Reds', 'RdPu', 'Purples', 'PuRd', 'PuBuGn', 'PuBu', 'OrRd', 'Oranges', 'Greys', 'Greens', 'GnBu', 'BuPu', 'BuGn', 'Blues', 'Set3', 'Set2', 'Set1', 'Pastel2', 'Pastel1', 'Paired', 'Dark2', 'Accent', 'inferno', 'plasma', 'vega10', 'vega20b', 'vega20c', 'viridis', 'thermal', 'haline', 'solar', 'ice', 'oxy', 'deep', 'dense', 'algae', 'matter', 'turbid', 'speed', 'amp', 'tempo', 'balance', 'delta', 'curl', 'phase', 'perceptually distinct'};
            app.colormapSelector.ValueChangedFcn = createCallbackFcn(app, @colormapSelectorChange, true);
            app.colormapSelector.Tooltip = {'Change the colormap of the currently selected overlay'};
            app.colormapSelector.Value = 'Select colormap to begin editing';
            app.InvertButton = uibutton(app.CreatecolormapTab, 'push');
            app.InvertButton.ButtonPushedFcn = createCallbackFcn(app, @invertBar, true);
            app.InvertButton.Text = 'Invert';
            app.BinsEditFieldLabel = uilabel(app.CreatecolormapTab);
            app.BinsEditFieldLabel.Text = 'Bins';
            app.binBox = uieditfield(app.CreatecolormapTab, 'numeric');
            app.binBox.ValueChangedFcn = createCallbackFcn(app, @binBoxChange, true);
            app.ButtonGroup = uibuttongroup(app.CreatecolormapTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @rgbSelection, true);
            app.ChangeleftmostcoloronbarButton = uiradiobutton(app.ButtonGroup);
            app.ChangeleftmostcoloronbarButton.Text = 'Change leftmost color on bar';
            app.ChangeleftmostcoloronbarButton.Value = true;
            app.ChangemiddlecoloronbarButton = uiradiobutton(app.ButtonGroup);
            app.ChangemiddlecoloronbarButton.Text = 'Change middle color on bar';
            app.ChangerightmostcoloronbarButton = uiradiobutton(app.ButtonGroup);
            app.ChangerightmostcoloronbarButton.Text = 'Change rightmost color on bar';
            app.rBox = uieditfield(app.CreatecolormapTab, 'numeric');
            app.rBox.Limits = [0 1];
            app.rBox.ValueChangedFcn = createCallbackFcn(app, @rBoxChange, true);
            app.rBox.Value = 1;
            app.gBox = uieditfield(app.CreatecolormapTab, 'numeric');
            app.gBox.Limits = [0 1];
            app.gBox.ValueChangedFcn = createCallbackFcn(app, @gBoxChange, true);
            app.gBox.Value = 1;
            app.bBox = uieditfield(app.CreatecolormapTab, 'numeric');
            app.bBox.Limits = [0 1];
            app.bBox.ValueChangedFcn = createCallbackFcn(app, @bBoxChange, true);
            app.bBox.Value = 1;
            app.RSliderLabel = uilabel(app.CreatecolormapTab);
            app.RSliderLabel.Text = 'R';
            app.r = uislider(app.CreatecolormapTab);
            app.r.Limits = [0 1];
            app.r.ValueChangedFcn = createCallbackFcn(app, @rSlider, true);
            app.r.Value = 1;
            app.GSliderLabel = uilabel(app.CreatecolormapTab);
            app.GSliderLabel.Text = 'G';
            app.g = uislider(app.CreatecolormapTab);
            app.g.Limits = [0 1];
            app.g.ValueChangedFcn = createCallbackFcn(app, @gSlider, true);
            app.g.Value = 1;
            app.BSliderLabel = uilabel(app.CreatecolormapTab);
            app.BSliderLabel.Text = 'B';
            app.b = uislider(app.CreatecolormapTab);
            app.b.Limits = [0 1];
            app.b.ValueChangedFcn = createCallbackFcn(app, @bSliders, true);
            app.b.Value = 1;
            app.includeMid = uiswitch(app.CreatecolormapTab, 'slider');
            app.includeMid.Items = {'No', 'Yes'};
            app.includeMid.ValueChangedFcn = createCallbackFcn(app, @inclMidButton, true);
            app.includeMid.Value = 'Yes';
            app.IncludemiddlecolorininterpolationLabel = uilabel(app.CreatecolormapTab);
            app.IncludemiddlecolorininterpolationLabel.Text = 'Include middle color in interpolation';
            app.CombineexistingcolormapsTab = uitab(app.TabGroup);
            app.CombineexistingcolormapsTab.Title = 'Combine existing colormaps';
            app.SelectcolormaponleftsideofbarPanel = uipanel(app.CombineexistingcolormapsTab);
            app.SelectcolormaponleftsideofbarPanel.Title = 'Select colormap on left side of bar';
            app.leftColormapSelector = uidropdown(app.SelectcolormaponleftsideofbarPanel);
            app.leftColormapSelector.Items = {'Select colormap', 'jet', 'parula', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'Spectral', 'RdYlBu', 'RdGy', 'RdBu', 'PuOr', 'PRGn', 'PiYG', 'BrBG', 'YlOrRd', 'YlOrBr', 'YlGnBu', 'YlGn', 'Reds', 'RdPu', 'Purples', 'PuRd', 'PuBuGn', 'PuBu', 'OrRd', 'Oranges', 'Greys', 'Greens', 'GnBu', 'BuPu', 'BuGn', 'Blues', 'Set3', 'Set2', 'Set1', 'Pastel2', 'Pastel1', 'Paired', 'Dark2', 'Accent', 'inferno', 'plasma', 'vega10', 'vega20b', 'vega20c', 'viridis', 'thermal', 'haline', 'solar', 'ice', 'oxy', 'deep', 'dense', 'algae', 'matter', 'turbid', 'speed', 'amp', 'tempo', 'balance', 'delta', 'curl', 'phase', 'perceptually distinct'};
            app.leftColormapSelector.ValueChangedFcn = createCallbackFcn(app, @leftColormapSelectorChange, true);
            app.leftColormapSelector.Tooltip = {'Change the colormap of the currently selected overlay'};
            app.leftColormapSelector.Value = 'Select colormap';
            app.BinsEditFieldLabel_2 = uilabel(app.SelectcolormaponleftsideofbarPanel);
            app.BinsEditFieldLabel_2.Text = 'Bins';
            app.leftBinBox = uieditfield(app.SelectcolormaponleftsideofbarPanel, 'numeric');
            app.leftBinBox.ValueChangedFcn = createCallbackFcn(app, @leftBinBoxChange, true);
            app.leftInvert = uibutton(app.SelectcolormaponleftsideofbarPanel, 'push');
            app.leftInvert.ButtonPushedFcn = createCallbackFcn(app, @leftInvertChange, true);
            app.leftInvert.Text = 'Invert';
            app.SelectcolormaponrightsideofbarPanel = uipanel(app.CombineexistingcolormapsTab);
            app.SelectcolormaponrightsideofbarPanel.Title = 'Select colormap on right side of bar';
            app.rightColormapSelector = uidropdown(app.SelectcolormaponrightsideofbarPanel);
            app.rightColormapSelector.Items = {'Select colormap to place on right side', 'jet', 'parula', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'lines', 'colorcube', 'prism', 'Spectral', 'RdYlBu', 'RdGy', 'RdBu', 'PuOr', 'PRGn', 'PiYG', 'BrBG', 'YlOrRd', 'YlOrBr', 'YlGnBu', 'YlGn', 'Reds', 'RdPu', 'Purples', 'PuRd', 'PuBuGn', 'PuBu', 'OrRd', 'Oranges', 'Greys', 'Greens', 'GnBu', 'BuPu', 'BuGn', 'Blues', 'Set3', 'Set2', 'Set1', 'Pastel2', 'Pastel1', 'Paired', 'Dark2', 'Accent', 'inferno', 'plasma', 'vega10', 'vega20b', 'vega20c', 'viridis', 'thermal', 'haline', 'solar', 'ice', 'oxy', 'deep', 'dense', 'algae', 'matter', 'turbid', 'speed', 'amp', 'tempo', 'balance', 'delta', 'curl', 'phase', 'perceptually distinct'};
            app.rightColormapSelector.ValueChangedFcn = createCallbackFcn(app, @rightColormapSelectorChange, true);
            app.rightColormapSelector.Tooltip = {'Change the colormap of the currently selected overlay'};
            app.rightColormapSelector.Value = 'Select colormap to place on right side';
            app.BinsEditFieldLabel_3 = uilabel(app.SelectcolormaponrightsideofbarPanel);
            app.BinsEditFieldLabel_3.Text = 'Bins';
            app.rightBinBox = uieditfield(app.SelectcolormaponrightsideofbarPanel, 'numeric');
            app.rightBinBox.ValueChangedFcn = createCallbackFcn(app, @rightBinBoxChange, true);
            app.rightInvert = uibutton(app.SelectcolormaponrightsideofbarPanel, 'push');
            app.rightInvert.ButtonPushedFcn = createCallbackFcn(app, @rightInvertChange, true);
            app.rightInvert.Text = 'Invert';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cbarCreator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end