classdef oSelect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CopyLimitsCheckBox      matlab.ui.control.CheckBox
        CopythresholdsCheckBox  matlab.ui.control.CheckBox
        MakeSelectionButton     matlab.ui.control.Button
        overlayListBox          matlab.ui.control.ListBox
        Label                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        CallingApp
    end
    
    properties (Access = private)
        actionType = []; % Description
        props = [];
    end
    
    methods (Access = private)
        % enforce selection of 2 items for some options...
        function enforceTwoItemSelection(app, src, event)
            currentSelection = src.Value; % 'src' is the listbox
            
            % If more than two items selected, revert to first two and alert the user
            if iscell(currentSelection) && length(currentSelection) > 2
                src.Value = currentSelection(1:2);
                uialert(app.UIFigure, ...
                        'You can only select exactly two items for this action.', ...
                        'Selection Limit');
            end
        
            if length(src.Value) == 2
                app.MakeSelectionButton.Enable = 'on';
            else
                app.MakeSelectionButton.Enable = 'off';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, labelAdd)
            app.CallingApp = mainapp;
            app.overlayListBox.Items = app.CallingApp.overlayListBox.Items;
            app.Label.Text = [app.Label.Text labelAdd];
            
            tmp1 = strfind(app.Label.Text,'delete');
            tmp2 = strfind(app.Label.Text,'copy');
            tmp3 = strfind(app.Label.Text,'duplicate');
            tmp4 = strfind(app.Label.Text,'save');
            tmp5 = strfind(app.Label.Text,'screenshot');
            tmp6 = strfind(app.Label.Text,'to mask');
            tmp6_2 = strfind(app.Label.Text,'to inverse mask');
            tmp7 = strfind(app.Label.Text,'reload');
            tmp8 = strfind(app.Label.Text,'combine');
            tmp9 = strfind(app.Label.Text,'average');
            tmp10 = strfind(app.Label.Text,'standard deviation');
            tmp11 = strfind(app.Label.Text,'standard error of the mean');
            tmp12 = strfind(app.Label.Text,'absolute value');
            tmp13 = strfind(app.Label.Text,'subtract');
            tmp14 = strfind(app.Label.Text,'multiply');
            tmp15 = strfind(app.Label.Text,'divide');
            tmp16 = strfind(lower(app.Label.Text),'pca');
            tmp17 = strfind(app.Label.Text,' to use as ROIs for 2D scatter plot');
            tmp20 = strfind(app.Label.Text,' to use as ROIs for 3D scatter plot');
            tmp18 = strfind(app.Label.Text,'invert positive and negative');
            tmp19 = strfind(app.Label.Text,'to align clusters for');
            tmp21 = strfind(app.Label.Text, ' to spin test (continuous maps)');
            tmp22 = strfind(app.Label.Text, ' to spin test (parcellations)');

            if ~isempty(tmp21)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'spinCont';
                app.overlayListBox.Multiselect = 'on';
                app.overlayListBox.ValueChangedFcn = @app.enforceTwoItemSelection;
                app.MakeSelectionButton.Enable = 'off'; % start disabled until two are selected
            end
            
            if ~isempty(tmp22)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'spinPar';
                app.overlayListBox.Multiselect = 'on';
                app.overlayListBox.ValueChangedFcn = @app.enforceTwoItemSelection;
                app.MakeSelectionButton.Enable = 'off'; % start disabled until two are selected
            end

            if ~isempty(tmp19)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'clustAlign';
            end
            if ~isempty(tmp18)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'invt';
            end
            if ~isempty(tmp1)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'delete';
            end
            if ~isempty(tmp2)
                app.actionType = 'copy';
            end
            if ~isempty(tmp3)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'duplicate';
            end
            if ~isempty(tmp4)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'save';
            end
            if ~isempty(tmp5)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'screenshot';
            end
            if ~isempty(tmp6)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'mask';
            end
            if ~isempty(tmp6_2)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'inverse mask';
            end
            if ~isempty(tmp7)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'reload';
            end
            if ~isempty(tmp8)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'combine';
            end
            if ~isempty(tmp9)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'average';
            end
            if ~isempty(tmp10)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'std dev';
            end
            if ~isempty(tmp11)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'std err';
            end
            if ~isempty(tmp12)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'abs';
            end
            if ~isempty(tmp13)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'subtract';
            end
            if ~isempty(tmp14)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'mutliply';
            end
            if ~isempty(tmp15)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'divide';
            end
            if ~isempty(tmp16)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'pca';
            end
             if ~isempty(tmp17)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'roi2d';
                app.overlayListBox.Multiselect = 'off';
             end
             if ~isempty(tmp20)
                 app.CopythresholdsCheckBox.Visible = 'off';
                 app.CopyLimitsCheckBox.Visible = 'off';
                 app.actionType = 'roi3d';
                 app.overlayListBox.Multiselect = 'off';
             end

            app.props = properties(app.CallingApp);
            for i = 1:length(app.props)
                try
                    app.CallingApp.(app.props{i}).Enable = 'off';
                catch
                end
            end
            app.CallingApp.lOver.Visible = 'off';
            app.CallingApp.lSurf.Visible = 'off';
        end

        % Button pushed function: MakeSelectionButton
        function makeSelection(app, event)

            si = [];
            s = [];
            hemi = [];
            if ~iscell(app.overlayListBox.Value)
                l = 1;
            else
                l = length(app.overlayListBox.Value);
            end
            
            for i = 1:l
                if ~iscell(app.overlayListBox.Value)
                    [st,~,sit] = intersect({app.overlayListBox.Value},app.overlayListBox.Items);
                else
                    [st,~,sit] = intersect(app.overlayListBox.Value{i},app.overlayListBox.Items);
                end
                si = [si; sit];
                s = [s; st];
                hemi = [hemi; {app.CallingApp.overlay.settings{sit}.hemi}];
            end
            id = find(si == 1);
            si(id) = [];
            
            switch app.actionType
                case 'spinCont'
                    o1 = app.CallingApp.overlay.bm{si(1)};
                    o2 = app.CallingApp.overlay.bm{si(2)};
                    if length(o1) == length(o2)
                        if strcmpi(app.CallingApp.overlay.settings{si(1)}.hemi,app.CallingApp.overlay.settings{si(2)}.hemi)
                            uialert(app.UIFigure,['Please select the spherical surface for the following hemisphere, based on the current surface template you are using in brainSurfer: ' app.CallingApp.overlay.settings{si(1)}.hemi],'More data needed');
                            [f,p,~] = uigetfile('*',['Please select the spherical surface for the following hemisphere, based on the current surface template you are using in brainSurfer: ' app.CallingApp.overlay.settings{si(1)}.hemi],'MultiSelect','off');
                            try
                                [verts,~] = read_surf([p f]);
                            catch
                            end
                            if isempty(verts)
                                try
                                    g = gifti([p f]);
                                    verts = g.vertices;
                                catch
                                    uialert(app.UIFigure,'Could not load your spherical surface file...please check the file you selected','Read error');
                                end
                            end

                            uialert(app.UIFigure,['Please select a mask of the medial wall of the spherical surface you just provided (' app.CallingApp.overlay.settings{si(1)}.hemi ' hemisphere)'],'More data needed');
                            [f,p,~] = uigetfile('*',['Please select a mask of the medial wall of the spherical surface you just provided (' app.CallingApp.overlay.settings{si(1)}.hemi ' hemisphere)'],'MultiSelect','off');
                            allverts.(app.CallingApp.overlay.settings{si(1)}.hemi) = verts;
                            s = setdiff({'left','right'},app.CallingApp.overlay.settings{si(1)}.hemi);
                            allverts.(s{1}) = [];
                            allverts.(app.CallingApp.overlay.settings{si(1)}.hemi) = verts;
                            [heminew1, heminew2, d, d2, sz, ci] = loadBrainData([p f], verts, {'lh', 'left', '_L', '.L.'}, {'rh', 'right', '_R', '.R.'});
                            if strcmpi(heminew1,app.CallingApp.overlay.settings{si(1)}.hemi)
                                m = d;
                            elseif strcmpi(heminew2,app.CallingApp.overlay.settings{si(1)}.hemi)
                                m = d2;
                            end
                            mid = find(m ~= 0);
                            o1(mid) = NaN;
                            o2(mid) = NaN;

                            numSpins = 5000;
                            observedCorr = corr(o1, o2, 'Type', 'Spearman', 'Rows', 'complete');
                            nullCorr = zeros(numSpins, 1);
                            kdTree = KDTreeSearcher(verts);
                            distfun = @(a, b) sqrt(bsxfun(@minus, bsxfun(@plus, sum(a.^2, 2), sum(b.^2, 1)), 2 * (a * b)));
                            progressDlg = uiprogressdlg(app.UIFigure, ...
                                'Title', 'Spin Test Progress', ...
                                'Message', 'Spin test executing...', ...
                                'Cancelable', 'on');

                            try
                                pValue = [];
                                for spin = 1:numSpins
                                    % Check if the user has canceled the operation
                                    if progressDlg.CancelRequested
                                        disp('Spin test canceled by user.');
                                        pValue = NaN; % Assign NaN to indicate cancellation
                                        break;
                                    end

                                    % Update the progress bar
                                    progressDlg.Value = spin / numSpins;
                                    progressDlg.Message = sprintf('Spin test executing... (%d/%d)', spin, numSpins);

                                    % Generate a random rotation matrix using QR decomposition
                                    A = randn(3,3);
                                    [Q, ~] = qr(A);
                                    if det(Q) < 0
                                        Q(:,1) = -Q(:,1);
                                    end
                                    R = Q;

                                    % Apply rotation to the vertices
                                    rotatedVert = verts * R;

                                    % Resample o1 to the rotated vertices using nearest neighbors
                                    idx = knnsearch(kdTree, rotatedVert);
                                    o1_rotated = o1(idx);

                                    % Compute Spearman's correlation for the rotated data
                                    nullCorr(spin) = corr(o1_rotated, o2, 'Type', 'Spearman', 'Rows', 'complete');
                                end

                                % Close the progress dialog
                                close(progressDlg);

                                % Check if the spin test was completed or canceled
                                if isnan(pValue)
                                    % Spin test was canceled; pValue is already set to NaN
                                    uialert(app.UIFigure,'Spin test was canceled by the user','Analysis cancelled');
                                else
                                    % Spin test was completed; proceed to calculate p-value
                                    % Calculate the two-tailed p-value
                                    pValue = (sum(abs(nullCorr) >= abs(observedCorr)) + 1) / (numSpins + 1);
                                    message = sprintf('Observed Spearman''s Correlation: %.4f\nP-value: %.4f', observedCorr, pValue);
                                    uialert(app.UIFigure,message,'Analysis complete','icon','success');
                                    uialert(app.CallingApp.brainSurferUIFigure,message,'Results--see ./analysisResults for log','icon','success');
                                    message = [sprintf('\nSpin test (continuous) performed between %s and %s', ...
                                        app.CallingApp.overlay.names{si(1)}, app.CallingApp.overlay.names{si(2)}), ...
                                        message];
                                    filePath = fullfile(app.CallingApp.pths.gPth, 'analysisResults', 'spinTestLog.txt');

                                    fileID = fopen(filePath, 'a'); % 'a' opens the file for appending
                                    if fileID == -1
                                        error('Failed to open the file for writing.');
                                    end
                                    fprintf(fileID, '%s\n', message);
                                    fclose(fileID);
                                end
                            catch ME
                                % Close the progress dialog in case of an error
                                close(progressDlg);
                                % Display the error message to the user
                                uialert(app.UIFigure, ME.message, 'Error');
                                % Re-throw the error to halt execution
                                rethrow(ME);
                            end
                        else
                            uialert(app.UIFigure,'Cannot proceed with analysis, your selected data is from different hemispheres','Data mismatch');
                        end
                    else
                        uialert(app.UIFigure,'Cannot proceed with analysis, your selected data is from different brains (different overlay shapes)','Data mismatch');
                    end

                case 'spinPar'
                    % Extract the data at the parcel-level
                    o1 = app.CallingApp.overlay.bm{si(1)};
                    o2 = app.CallingApp.overlay.bm{si(2)};

                    % Check if the number of parcels matches
                    if length(o1) == length(o2)
                        if strcmpi(app.CallingApp.overlay.settings{si(1)}.hemi, app.CallingApp.overlay.settings{si(2)}.hemi)

                            % Prompt user to select the spherical surface file
                            uialert(app.UIFigure,['Please select the spherical surface file for the ' ...
                                app.CallingApp.overlay.settings{si(1)}.hemi ' hemisphere'],'More data needed');
                            [f, p, ~] = uigetfile('*',['Select surface file for ' app.CallingApp.overlay.settings{si(1)}.hemi ' hemisphere'],'MultiSelect','off');
                            if isequal(f,0)
                                uialert(app.UIFigure,'No surface file selected','Cancelled');
                                return;
                            end

                            % Attempt to read the spherical surface
                            try
                                [verts,~] = read_surf([p f]);
                            catch
                            end
                            if isempty(verts)
                                try
                                    g = gifti([p f]);
                                    verts = g.vertices;
                                catch
                                    uialert(app.UIFigure,'Could not load your spherical surface file...please check the file you selected','Read error');
                                    return;
                                end
                            end

                            % Prompt user to select the parcellation file
                            uialert(app.UIFigure,['Please select the parcellation file for the ' ...
                                app.CallingApp.overlay.settings{si(1)}.hemi ' hemisphere'],'More data needed');
                            [parcFile, parcPath, ~] = uigetfile('*',['Select parcellation file for ' app.CallingApp.overlay.settings{si(1)}.hemi ' hemisphere'],'MultiSelect','off');
                            if isequal(parcFile,0)
                                uialert(app.UIFigure,'No parcellation file selected','Cancelled');
                                return;
                            end
                            allverts.(app.CallingApp.overlay.settings{si(1)}.hemi) = verts;
                            s = setdiff({'left','right'},app.CallingApp.overlay.settings{si(1)}.hemi);
                            allverts.(s{1}) = [];
                            allverts.(app.CallingApp.overlay.settings{si(1)}.hemi) = verts;
                            [atlas, ~] = loadAndProcessAtlas({[parcPath parcFile]}, {app.CallingApp.overlay.settings{si(1)}.hemi}, allverts, []);
                            rois = atlas.clusters.(app.CallingApp.overlay.settings{si(1)}.hemi);
                            atlas = atlas.bm.(app.CallingApp.overlay.settings{si(1)}.hemi);
                            nROIs = length(rois);
                            centroid = zeros(nROIs,3);

                            for i = 1:nROIs
                                centroid(i,:) = mean(verts(rois{i},:), 1);
                            end

                            uialert(app.UIFigure,['Please select a mask of the medial wall of the spherical surface you just provided (' app.CallingApp.overlay.settings{si(1)}.hemi ' hemisphere)'],'More data needed');
                            [f,p,~] = uigetfile('*',['Please select a mask of the medial wall of the spherical surface you just provided (' app.CallingApp.overlay.settings{si(1)}.hemi ' hemisphere)'],'MultiSelect','off');
                            [heminew1, heminew2, d, d2, sz, ci] = loadBrainData([p f], verts, {'lh', 'left', '_L', '.L.'}, {'rh', 'right', '_R', '.R.'});
                            if strcmpi(heminew1,app.CallingApp.overlay.settings{si(1)}.hemi)
                                m = d;
                            elseif strcmpi(heminew2,app.CallingApp.overlay.settings{si(1)}.hemi)
                                m = d2;
                            end

                            mid = find(m ~= 0);
                            o1(mid) = NaN;
                            o2(mid) = NaN;

                            % Compute one value per ROI from o1 and o2.
                            % Since each ROI has uniform data, we can take the mean over the ROI's vertices (ignoring NaNs).
                            roi_o1 = nan(nROIs,1);
                            roi_o2 = nan(nROIs,1);
                            for i = 1:nROIs
                                roi_o1(i) = nanmean(o1(rois{i}));
                                roi_o2(i) = nanmean(o2(rois{i}));
                            end

                            observedCorr = corr(roi_o1, roi_o2, 'Type', 'Spearman', 'Rows', 'complete');

                            numSpins = 5000;
                            perm_id = zeros(nROIs,numSpins);

                            % Create progress dialog
                            progressDlg = uiprogressdlg(app.UIFigure, ...
                                'Title', 'Spin Test Progress (Parcellation)', ...
                                'Message', 'Spin test executing...', ...
                                'Cancelable', 'on');
                            pValue = [];
                            try
                                r_count = 0;
                                c = 0; % count unsuccessful attempts

                                while (r_count < numSpins)
                                    % Check if the user has canceled the operation
                                    if progressDlg.CancelRequested
                                        disp('Spin test (parcellation) canceled by user.');
                                        pValue = NaN; % Assign NaN to indicate cancellation
                                        break;
                                    end

                                    % Update the progress bar
                                    progressDlg.Value = r_count / numSpins;
                                    progressDlg.Message = sprintf('Spin test executing... (%d/%d)', r_count+1, numSpins);

                                    % Generate a random rotation (uniformly sampled from SO(3))
                                    A = randn(3,3);
                                    [Q, Rtemp] = qr(A);
                                    Q = Q*diag(sign(diag(Rtemp)));
                                    if det(Q)<0
                                        Q(:,1) = -Q(:,1);
                                    end

                                    coord_l_rot = centroid * Q;

                                    % Compute distance matrix between original and rotated coordinates
                                    dist_l = pdist2(centroid, coord_l_rot);

                                    % Solve assignment problem using munkres to minimize sum of distances
                                    assignment = munkres(dist_l);
                                    [~, rot_map] = max(assignment, [], 2);

                                    % Check if permutation maps each ROI to itself (which we want to exclude)
                                    if ~all(rot_map == (1:nROIs))
                                        r_count = r_count + 1;
                                        perm_id(:, r_count) = rot_map(:);
                                    else
                                        c = c + 1;
                                    end
                                end

                                % Close the progress dialog
                                close(progressDlg);

                                if isnan(pValue)
                                    % Spin test was canceled; pValue is already set to NaN
                                    uialert(app.UIFigure,'Spin test was canceled by the user','Analysis cancelled');
                                else
                                    % Compute null distributions and p-value
                                    x = roi_o1;
                                    y = roi_o2;
                                    nperm = size(perm_id,2);

                                    rho_null_xy = zeros(nperm,1);
                                    rho_null_yx = zeros(nperm,1);

                                    for r = 1:nperm
                                        x_perm = x(perm_id(:,r)); % permute x
                                        y_perm = y(perm_id(:,r)); % permute y

                                        % correlate permuted x with unpermuted y
                                        rho_null_xy(r) = corr(x_perm, y, 'type', 'Spearman', 'rows', 'complete');
                                        % correlate permuted y with unpermuted x
                                        rho_null_yx(r) = corr(y_perm, x, 'type', 'Spearman', 'rows', 'complete');
                                    end

                                    rho_emp = observedCorr;
                                    if rho_emp > 0
                                        p_perm_xy = (sum(rho_null_xy > rho_emp) + 1)/(nperm + 1);
                                        p_perm_yx = (sum(rho_null_yx > rho_emp) + 1)/(nperm + 1);
                                    else
                                        p_perm_xy = (sum(rho_null_xy < rho_emp) + 1)/(nperm + 1);
                                        p_perm_yx = (sum(rho_null_yx < rho_emp) + 1)/(nperm + 1);
                                    end

                                    pValue = (p_perm_xy + p_perm_yx)/2;

                                    message = sprintf('Observed Spearman''s Correlation: %.4f\nP-value: %.4f', observedCorr, pValue);
                                    uialert(app.UIFigure,message,'Analysis complete','icon','success');
                                    uialert(app.CallingApp.brainSurferUIFigure,message,'Results--see ./analysisResults for log','icon','success');
                                    message = [sprintf('\nSpin test (parcellation) performed between %s and %s', ...
                                        app.CallingApp.overlay.names{si(1)}, app.CallingApp.overlay.names{si(2)}), message];
                                    filePath = fullfile(app.CallingApp.pths.gPth, 'analysisResults', 'spinTestLog.txt');

                                    fileID = fopen(filePath, 'a'); % 'a' for appending
                                    if fileID == -1
                                        error('Failed to open the file for writing.');
                                    end
                                    fprintf(fileID, '%s\n', message);
                                    fclose(fileID);
                                end
                            catch ME
                                % Close the progress dialog in case of an error
                                close(progressDlg);
                                % Display the error message to the user
                                uialert(app.UIFigure, ME.message, 'Error');
                                % Re-throw the error to halt execution
                                rethrow(ME);
                            end

                        else
                            uialert(app.UIFigure,'Cannot proceed with analysis, your selected data is from different hemispheres','Data mismatch');
                        end
                    else
                        uialert(app.UIFigure,'Cannot proceed with analysis, your selected data is from different brains (different overlay shapes)','Data mismatch');
                    end

                case 'roi2d'
                    % make sure you can only select one item for this option
                    if ~isempty(app.CallingApp.overlay.settings{si}.threshSmoothData)
                        d = app.CallingApp.overlay.settings{si}.threshSmoothData;
                    else
                        d = app.CallingApp.overlay.bm{si};
                    end

                    roi = unique(d);
                    id = find(roi == 0);
                    roi(id) = [];

                    si2 = [];
                    for i = 1:length(app.CallingApp.overlayListBox.Value)
                        [st,~,sit] = intersect(app.CallingApp.overlayListBox.Value(i),app.CallingApp.overlayListBox.Items);
                        si2 = [si2; sit];
                    end
                    id = find(si2 == 1);
                    si2(id) = [];

                    musi = [];
                    for i = 1:length(si2)
                        if ~isempty(app.CallingApp.overlay.settings{si2(i)}.multiData)
                            musi = [musi; i];
                        end
                    end
                    if length(musi) > 1
                        musi = musi(1);
                    end

                    if ~isempty(musi)
                        allD = [];
                        grp = [];
                        for i = 1:length(roi)
                            vId = find(d == roi(i));
                            tmp = app.CallingApp.overlay.settings{si2(musi)}.multiData(vId,:);
                            tf = roi(i) >= app.CallingApp.overlay.settings{si}.color.dataMap;
                            id = find(tf == 1);
                            m = id(end);
                            cData(i,:) = app.CallingApp.overlay.settings{si}.color.map(m,:);
                            allD = [allD; tmp];
                            grp = [grp; ones(size(tmp,1),1)*i];
                        end
                    end

                    %hold(app.CallingApp.hist2D,'on')
                    figure;
                    %                         sh = scatterhist(allD(:,1),allD(:,2),'Group',grp,'Kernel','on','Location','SouthEast',...
                    %                             'Direction','in','Color',cData,'LineStyle','-',...
                    %                             'LineWidth',3,'Marker','+od','MarkerSize',[4,5,6]);
                    %
                    s = scatterhistogram(allD(:,1),allD(:,2),'GroupData',grp,...
                        'HistogramDisplayStyle','smooth', ...
                        'LineStyle','-','LineWidth',2,'LegendVisible','on');
                    s.MarkerAlpha = repmat(0.2,1,length(roi));
                    s.MarkerSize = repmat(70,1,length(roi));
                    s.Color = cData;
                    s.HistogramDisplayStyle = 'smooth';
                    set(gcf,'color','w');
                    yl = app.CallingApp.overlay.settings{si2}.TwoDim1Name;
                    xl = app.CallingApp.overlay.settings{si2}.TwoDim2Name;
                    id = strfind(yl,'_');
                    yl(id) = ' ';
                    id = strfind(xl,'_');
                    xl(id) = ' ';
                    ylabel(yl)
                    xlabel(xl)

                case 'roi3d'
                    % make sure you can only select one item for this option
                    if ~isempty(app.CallingApp.overlay.settings{si}.threshSmoothData)
                        d = app.CallingApp.overlay.settings{si}.threshSmoothData;
                    else
                        d = app.CallingApp.overlay.bm{si};
                    end

                    roi = unique(d);
                    id = find(roi == 0);
                    roi(id) = [];

                    si2 = [];
                    for i = 1:length(app.CallingApp.overlayListBox.Value)
                        [st,~,sit] = intersect(app.CallingApp.overlayListBox.Value(i),app.CallingApp.overlayListBox.Items);
                        si2 = [si2; sit];
                    end
                    id = find(si2 == 1);
                    si2(id) = [];

                    musi = [];
                    for i = 1:length(si2)
                        if ~isempty(app.CallingApp.overlay.settings{si2(i)}.multiData)
                            musi = [musi; i];
                        end
                    end
                    if length(musi) > 1
                        musi = musi(1);
                    end

                    if ~isempty(musi)
                        for i = 1:length(roi)
                            vId = find(d == roi(i));
                            tmp = app.CallingApp.overlay.settings{si2(musi)}.multiData(vId,:);
                            tf = roi(i) >= app.CallingApp.overlay.settings{si}.color.dataMap;
                            id = find(tf == 1);
                            m = id(end);
                            cData(i,:) = app.CallingApp.overlay.settings{si}.color.map(m,:);

                            if i == 1
                                figure;
                                s = scatter3(tmp(:,1),tmp(:,2),tmp(:,3));
                                s.SizeData = 70;
                                s.MarkerFaceColor = cData(i,:);
                                s.MarkerFaceAlpha = 0.3;
                                s.MarkerEdgeAlpha = 0;
                                hold on
                            else
                                s = scatter3(tmp(:,1),tmp(:,2),tmp(:,3));
                                s.SizeData = 70;
                                s.MarkerFaceColor = cData(i,:);
                                s.MarkerFaceAlpha = 0.3;
                                s.MarkerEdgeAlpha = 0;
                                if i == length(roi)
                                    hold off
                                end
                            end
                        end

                        set(gcf,'color','w');
                        yl = app.CallingApp.overlay.settings{si2}.ThreeDim1Name;
                        xl = app.CallingApp.overlay.settings{si2}.ThreeDim2Name;
                        zl = app.CallingApp.overlay.settings{si2}.ThreeDim3Name;
                        id = strfind(yl,'_');
                        yl(id) = ' ';
                        id = strfind(xl,'_');
                        xl(id) = ' ';
                        id = strfind(zl,'_');
                        zl(id) = ' ';
                        zlabel(zl)
                        ylabel(yl)
                        xlabel(xl)
                    end
                case 'clustAlign'


                case 'combine'
                    
                    if numel(unique(hemi))==1
                        ns = length(app.CallingApp.overlay.bm) + 1;
                        nmTmp = app.CallingApp.overlay.names(si);
                        for i = 1:length(nmTmp)
                            [~, nm{i}, e] = fileparts(nmTmp(i));
                            if length(nm{i}) > 4
                                if strcmpi(nm{i}(end-3:end),'.nii') | strcmpi(nm{i}(end-3:end),'.gii')
                                    e = [nm{i}(end-3:end) e];
                                    nm{i} = nm{i}(1:end-4);
                                end
                            end
                            if i == length(nmTmp)
                                nm{i} = [nm{i} e];
                            end
                        end
                        nm = strjoin(app.CallingApp.overlay.names(si),'_CombinedWith_');

                        
                        int = 1;
                        while int == 1
                            it = intersect(app.CallingApp.overlayListBox.Items,nm);
                            if ~isempty(it)
                                int = 1;
                                nm = [nm '_COPY'];
                            else
                                int = 0;
                            end
                        end
                        
                        d = [];
                        for i = 1:length(si)
                            if ~isempty(app.CallingApp.overlay.settings{si(i)}.threshSmoothData)
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.settings{si(i)}.threshSmoothData];
                                else
                                    d = app.CallingApp.overlay.settings{si(i)}.threshSmoothData;
                                end
                            else
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.bm{si(i)}];
                                else
                                    d = app.CallingApp.overlay.bm{si(i)};
                                end
                            end
                        end
                        
                        d = sum(d,2);

                        app.CallingApp.overlayListBox.Items = [app.CallingApp.overlayListBox.Items  nm];
                        app.CallingApp.overlay.paths(ns) = {app.CallingApp.overlay.paths{si(1)}};
                        app.CallingApp.overlay.names(ns) = {nm};
                        app.CallingApp.overlay.bm{ns} = d;
                        app.CallingApp.overlay.bmB{ns} = d;
                        app.CallingApp.overlay.settings = horzcat(app.CallingApp.overlay.settings,app.CallingApp.defaultSetOver);
                        app.CallingApp.overlay.settings{ns}.hemi = app.CallingApp.overlay.settings{si(1)}.hemi;
                        app.CallingApp.overlay.settings{ns}.limMinOver = min(d);
                        app.CallingApp.overlay.settings{ns}.limMaxOver = max(d);

                        app.CallingApp.modOverlaySelect.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD2.Items = app.CallingApp.overlayListBox.Items;

                        app.CallingApp.ThreeD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD2.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD3.Items = app.CallingApp.overlayListBox.Items;
                    else
                        uialert(app.brainSurferUIFigure,'You cannot combine maps from two different hemispheres','Action unavailable');
                    end
                case 'average'
                    if numel(unique(hemi))==1
                        ns = length(app.CallingApp.overlay.bm) + 1;
                        nmTmp = app.CallingApp.overlay.names(si);
                        for i = 1:length(nmTmp)
                            [~, nm{i}, e] = fileparts(nmTmp(i));
                            if length(nm{i}) > 4
                                if strcmpi(nm{i}(end-3:end),'.nii') | strcmpi(nm{i}(end-3:end),'.gii')
                                    e = [nm{i}(end-3:end) e];
                                    nm{i} = nm{i}(1:end-4);
                                end
                            end
                            if i == length(nmTmp)
                                nm{i} = [nm{i} e];
                            end
                        end
                        nm = strjoin(app.CallingApp.overlay.names(si),'_CombinedWith_');


                        int = 1;
                        while int == 1
                            it = intersect(app.CallingApp.overlayListBox.Items,nm);
                            if ~isempty(it)
                                int = 1;
                                nm = [nm '_COPY'];
                            else
                                int = 0;
                            end
                        end

                        d = [];
                        for i = 1:length(si)
                            if ~isempty(app.CallingApp.overlay.settings{si(i)}.threshSmoothData)
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.settings{si(i)}.threshSmoothData];
                                else
                                    d = app.CallingApp.overlay.settings{si(i)}.threshSmoothData;
                                end
                            else
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.bm{si(i)}];
                                else
                                    d = app.CallingApp.overlay.bm{si(i)};
                                end
                            end
                        end

                        d = mean(d,2);

                        app.CallingApp.overlayListBox.Items = [app.CallingApp.overlayListBox.Items  nm];
                        app.CallingApp.overlay.paths(ns) = {app.CallingApp.overlay.paths{si(1)}};
                        app.CallingApp.overlay.names(ns) = {nm};
                        app.CallingApp.overlay.bm{ns} = d;
                        app.CallingApp.overlay.bmB{ns} = d;
                        app.CallingApp.overlay.settings = horzcat(app.CallingApp.overlay.settings,app.CallingApp.defaultSetOver);
                        app.CallingApp.overlay.settings{ns}.hemi = app.CallingApp.overlay.settings{si(1)}.hemi;
                        app.CallingApp.overlay.settings{ns}.limMinOver = min(d);
                        app.CallingApp.overlay.settings{ns}.limMaxOver = max(d);

                        app.CallingApp.modOverlaySelect.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD2.Items = app.CallingApp.overlayListBox.Items;

                        app.CallingApp.ThreeD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD2.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD3.Items = app.CallingApp.overlayListBox.Items;
                    else
                        uialert(app.brainSurferUIFigure,'You cannot average maps from two different hemispheres','Action unavailable');
                    end
                    
                case 'std dev'
                    if numel(unique(hemi))==1
                        ns = length(app.CallingApp.overlay.bm) + 1;
                        nmTmp = app.CallingApp.overlay.names(si);
                        for i = 1:length(nmTmp)
                            [~, nm{i}, e] = fileparts(nmTmp(i));
                            if length(nm{i}) > 4
                                if strcmpi(nm{i}(end-3:end),'.nii') | strcmpi(nm{i}(end-3:end),'.gii')
                                    e = [nm{i}(end-3:end) e];
                                    nm{i} = nm{i}(1:end-4);
                                end
                            end
                            if i == length(nmTmp)
                                nm{i} = [nm{i} e];
                            end
                        end
                        nm = strjoin(app.CallingApp.overlay.names(si),'_CombinedWith_');


                        int = 1;
                        while int == 1
                            it = intersect(app.CallingApp.overlayListBox.Items,nm);
                            if ~isempty(it)
                                int = 1;
                                nm = [nm '_COPY'];
                            else
                                int = 0;
                            end
                        end

                        d = [];
                        for i = 1:length(si)
                            if ~isempty(app.CallingApp.overlay.settings{si(i)}.threshSmoothData)
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.settings{si(i)}.threshSmoothData];
                                else
                                    d = app.CallingApp.overlay.settings{si(i)}.threshSmoothData;
                                end
                            else
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.bm{si(i)}];
                                else
                                    d = app.CallingApp.overlay.bm{si(i)};
                                end
                            end
                        end

                        d = std(d,[],2);

                        app.CallingApp.overlayListBox.Items = [app.CallingApp.overlayListBox.Items  nm];
                        app.CallingApp.overlay.paths(ns) = {app.CallingApp.overlay.paths{si(1)}};
                        app.CallingApp.overlay.names(ns) = {nm};
                        app.CallingApp.overlay.bm{ns} = d;
                        app.CallingApp.overlay.bmB{ns} = d;
                        app.CallingApp.overlay.settings = horzcat(app.CallingApp.overlay.settings,app.CallingApp.defaultSetOver);
                        app.CallingApp.overlay.settings{ns}.hemi = app.CallingApp.overlay.settings{si(1)}.hemi;
                        app.CallingApp.overlay.settings{ns}.limMinOver = min(d);
                        app.CallingApp.overlay.settings{ns}.limMaxOver = max(d);

                        app.CallingApp.modOverlaySelect.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD2.Items = app.CallingApp.overlayListBox.Items;

                        app.CallingApp.ThreeD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD2.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD3.Items = app.CallingApp.overlayListBox.Items;
                    else
                        uialert(app.brainSurferUIFigure,'You cannot std dev maps from two different hemispheres','Action unavailable');
                    end
                    
                case 'std err'
                    if numel(unique(hemi))==1
                        ns = length(app.CallingApp.overlay.bm) + 1;
                        nmTmp = app.CallingApp.overlay.names(si);
                        for i = 1:length(nmTmp)
                            [~, nm{i}, e] = fileparts(nmTmp(i));
                            if length(nm{i}) > 4
                                if strcmpi(nm{i}(end-3:end),'.nii') | strcmpi(nm{i}(end-3:end),'.gii')
                                    e = [nm{i}(end-3:end) e];
                                    nm{i} = nm{i}(1:end-4);
                                end
                            end
                            if i == length(nmTmp)
                                nm{i} = [nm{i} e];
                            end
                        end
                        nm = strjoin(app.CallingApp.overlay.names(si),'_CombinedWith_');


                        int = 1;
                        while int == 1
                            it = intersect(app.CallingApp.overlayListBox.Items,nm);
                            if ~isempty(it)
                                int = 1;
                                nm = [nm '_COPY'];
                            else
                                int = 0;
                            end
                        end

                        d = [];
                        for i = 1:length(si)
                            if ~isempty(app.CallingApp.overlay.settings{si(i)}.threshSmoothData)
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.settings{si(i)}.threshSmoothData];
                                else
                                    d = app.CallingApp.overlay.settings{si(i)}.threshSmoothData;
                                end
                            else
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.bm{si(i)}];
                                else
                                    d = app.CallingApp.overlay.bm{si(i)};
                                end
                            end
                        end

                        d = std(d,[],2)./sqrt(size(d,2));

                        app.CallingApp.overlayListBox.Items = [app.CallingApp.overlayListBox.Items  nm];
                        app.CallingApp.overlay.paths(ns) = {app.CallingApp.overlay.paths{si(1)}};
                        app.CallingApp.overlay.names(ns) = {nm};
                        app.CallingApp.overlay.bm{ns} = d;
                        app.CallingApp.overlay.bmB{ns} = d;
                        app.CallingApp.overlay.settings = horzcat(app.CallingApp.overlay.settings,app.CallingApp.defaultSetOver);
                        app.CallingApp.overlay.settings{ns}.hemi = app.CallingApp.overlay.settings{si(1)}.hemi;
                        app.CallingApp.overlay.settings{ns}.limMinOver = min(d);
                        app.CallingApp.overlay.settings{ns}.limMaxOver = max(d);

                        app.CallingApp.modOverlaySelect.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD2.Items = app.CallingApp.overlayListBox.Items;

                        app.CallingApp.ThreeD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD2.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD3.Items = app.CallingApp.overlayListBox.Items;
                    else
                        uialert(app.brainSurferUIFigure,'You cannot std err maps from two different hemispheres','Action unavailable');
                    end
                    
                case 'abs'
                    [c,ia,~] = intersect(app.CallingApp.overlayListBox.Items,app.CallingApp.overlayListBox.Value);
                    for i = 1:length(si)
                        if ~isempty(app.CallingApp.overlay.settings{si(i)}.threshSmoothData)
                            app.CallingApp.overlay.settings{si(i)}.threshSmoothData = abs(app.CallingApp.overlay.settings{si(i)}.threshSmoothData);
                            app.CallingApp.overlay.settings{si(i)}.limMinOver = min(app.CallingApp.overlay.settings{si(i)}.threshSmoothData);
                            app.CallingApp.overlay.settings{si(i)}.limMaxOver = max(app.CallingApp.overlay.settings{si(i)}.threshSmoothData);
                        end
                        app.CallingApp.overlay.bm{si(i)} = abs(app.CallingApp.overlay.bm{si(i)});
                        app.CallingApp.overlay.settings{si(i)}.limMinOver = min(app.CallingApp.overlay.bm{si(i)});
                        app.CallingApp.overlay.settings{si(i)}.limMaxOver = max(app.CallingApp.overlay.bm{si(i)});
                    end
                    if intersect(ia,si)
                        app.CallingApp = overPatch(app.CallingApp,'false','false','false');
                    end
                    
                case 'invt'
                    [c,ia,~] = intersect(app.CallingApp.overlayListBox.Items,app.CallingApp.overlayListBox.Value);
                    for i = 1:length(si)
                        if ~isempty(app.CallingApp.overlay.settings{si(i)}.threshSmoothData)
                            app.CallingApp.overlay.settings{si(i)}.threshSmoothData = -1*(app.CallingApp.overlay.settings{si(i)}.threshSmoothData);
                            app.CallingApp.overlay.settings{si(i)}.limMinOver = min(app.CallingApp.overlay.settings{si(i)}.threshSmoothData);
                            app.CallingApp.overlay.settings{si(i)}.limMaxOver = max(app.CallingApp.overlay.settings{si(i)}.threshSmoothData);
                        end
                        app.CallingApp.overlay.bm{si(i)} = -1*(app.CallingApp.overlay.bm{si(i)});
                        app.CallingApp.overlay.settings{si(i)}.limMinOver = min(app.CallingApp.overlay.bm{si(i)});
                        app.CallingApp.overlay.settings{si(i)}.limMaxOver = max(app.CallingApp.overlay.bm{si(i)});
                    end
                    if intersect(ia,si)
                        app.CallingApp = overPatch(app.CallingApp,'false','false','false');
                    end

                case 'subtract'
                    if numel(unique(hemi))==1
                        ns = length(app.CallingApp.overlay.bm) + 1;
                        nmTmp = app.CallingApp.overlay.names(si);
                        for i = 1:length(nmTmp)
                            [~, nm{i}, e] = fileparts(nmTmp(i));
                            if length(nm{i}) > 4
                                if strcmpi(nm{i}(end-3:end),'.nii') | strcmpi(nm{i}(end-3:end),'.gii')
                                    e = [nm{i}(end-3:end) e];
                                    nm{i} = nm{i}(1:end-4);
                                end
                            end
                            if i == length(nmTmp)
                                nm{i} = [nm{i} e];
                            end
                        end
                        nm = strjoin(app.CallingApp.overlay.names(si),'_DifferenceWith_');


                        int = 1;
                        while int == 1
                            it = intersect(app.CallingApp.overlayListBox.Items,nm);
                            if ~isempty(it)
                                int = 1;
                                nm = [nm '_COPY'];
                            else
                                int = 0;
                            end
                        end

                        d = [];
                        for i = 1:length(si)
                            if ~isempty(app.CallingApp.overlay.settings{si(i)}.threshSmoothData)
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.settings{si(i)}.threshSmoothData];
                                else
                                    d = app.CallingApp.overlay.settings{si(i)}.threshSmoothData;
                                end
                            else
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.bm{si(i)}];
                                else
                                    d = app.CallingApp.overlay.bm{si(i)};
                                end
                            end
                        end
    
                        d = diff(fliplr(d),1,2);

                        app.CallingApp.overlayListBox.Items = [app.CallingApp.overlayListBox.Items  nm];
                        app.CallingApp.overlay.paths(ns) = {app.CallingApp.overlay.paths{si(1)}};
                        app.CallingApp.overlay.names(ns) = {nm};
                        app.CallingApp.overlay.bm{ns} = d;
                        app.CallingApp.overlay.bmB{ns} = d;
                        app.CallingApp.overlay.settings = horzcat(app.CallingApp.overlay.settings,app.CallingApp.defaultSetOver);
                        app.CallingApp.overlay.settings{ns}.hemi = app.CallingApp.overlay.settings{si(1)}.hemi;
                        app.CallingApp.overlay.settings{ns}.limMinOver = min(d);
                        app.CallingApp.overlay.settings{ns}.limMaxOver = max(d);

                        app.CallingApp.modOverlaySelect.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD2.Items = app.CallingApp.overlayListBox.Items;

                        app.CallingApp.ThreeD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD2.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD3.Items = app.CallingApp.overlayListBox.Items;
                    else
                        uialert(app.brainSurferUIFigure,'You cannot subtract maps from two different hemispheres','Action unavailable');
                    end
                    
                case 'multiply'
                    if numel(unique(hemi))==1
                        ns = length(app.CallingApp.overlay.bm) + 1;
                        nmTmp = app.CallingApp.overlay.names(si);
                        for i = 1:length(nmTmp)
                            [~, nm{i}, e] = fileparts(nmTmp(i));
                            if length(nm{i}) > 4
                                if strcmpi(nm{i}(end-3:end),'.nii') | strcmpi(nm{i}(end-3:end),'.gii')
                                    e = [nm{i}(end-3:end) e];
                                    nm{i} = nm{i}(1:end-4);
                                end
                            end
                            if i == length(nmTmp)
                                nm{i} = [nm{i} e];
                            end
                        end
                        nm = strjoin(app.CallingApp.overlay.names(si),'_MultipliedBy_');


                        int = 1;
                        while int == 1
                            it = intersect(app.CallingApp.overlayListBox.Items,nm);
                            if ~isempty(it)
                                int = 1;
                                nm = [nm '_COPY'];
                            else
                                int = 0;
                            end
                        end

                        d = [];
                        for i = 1:length(si)
                            if ~isempty(app.CallingApp.overlay.settings{si(i)}.threshSmoothData)
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.settings{si(i)}.threshSmoothData];
                                else
                                    d = app.CallingApp.overlay.settings{si(i)}.threshSmoothData;
                                end
                            else
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.bm{si(i)}];
                                else
                                    d = app.CallingApp.overlay.bm{si(i)};
                                end
                            end
                        end

                        d = prod(d,2);

                        app.CallingApp.overlayListBox.Items = [app.CallingApp.overlayListBox.Items  nm];
                        app.CallingApp.overlay.paths(ns) = {app.CallingApp.overlay.paths{si(1)}};
                        app.CallingApp.overlay.names(ns) = {nm};
                        app.CallingApp.overlay.bm{ns} = d;
                        app.CallingApp.overlay.bmB{ns} = d;
                        app.CallingApp.overlay.settings = horzcat(app.CallingApp.overlay.settings,app.CallingApp.defaultSetOver);
                        app.CallingApp.overlay.settings{ns}.hemi = app.CallingApp.overlay.settings{si(1)}.hemi;
                        app.CallingApp.overlay.settings{ns}.limMinOver = min(d);
                        app.CallingApp.overlay.settings{ns}.limMaxOver = max(d);

                        app.CallingApp.modOverlaySelect.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD2.Items = app.CallingApp.overlayListBox.Items;

                        app.CallingApp.ThreeD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD2.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD3.Items = app.CallingApp.overlayListBox.Items;
                    else
                        uialert(app.brainSurferUIFigure,'You cannot multiply maps from two different hemispheres','Action unavailable');
                    end

                case 'divide'
                    if numel(unique(hemi))==1
                        ns = length(app.CallingApp.overlay.bm) + 1;
                        nmTmp = app.CallingApp.overlay.names(si);
                        for i = 1:2%length(nmTmp)
                            [~, nm{i}, e] = fileparts(nmTmp(i));
                            if length(nm{i}) > 4
                                if strcmpi(nm{i}(end-3:end),'.nii') | strcmpi(nm{i}(end-3:end),'.gii')
                                    e = [nm{i}(end-3:end) e];
                                    nm{i} = nm{i}(1:end-4);
                                end
                            end
                            if i == length(nmTmp)
                                nm{i} = [nm{i} e];
                            end
                        end
                        nm = strjoin(app.CallingApp.overlay.names(si),'_DividedBy_');


                        int = 1;
                        while int == 1
                            it = intersect(app.CallingApp.overlayListBox.Items,nm);
                            if ~isempty(it)
                                int = 1;
                                nm = [nm '_COPY'];
                            else
                                int = 0;
                            end
                        end

                        d = [];
                        for i = 1:length(si)
                            if ~isempty(app.CallingApp.overlay.settings{si(i)}.threshSmoothData)
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.settings{si(i)}.threshSmoothData];
                                else
                                    d = app.CallingApp.overlay.settings{si(i)}.threshSmoothData;
                                end
                            else
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.bm{si(i)}];
                                else
                                    d = app.CallingApp.overlay.bm{si(i)};
                                end
                            end
                        end                        
                            d = d(:,1)./d(:,2);

                            app.CallingApp.overlayListBox.Items = [app.CallingApp.overlayListBox.Items  nm];
                            app.CallingApp.overlay.paths(ns) = {app.CallingApp.overlay.paths{si(1)}};
                            app.CallingApp.overlay.names(ns) = {nm};
                            app.CallingApp.overlay.bm{ns} = d;
                            app.CallingApp.overlay.bmB{ns} = d;
                            app.CallingApp.overlay.settings = horzcat(app.CallingApp.overlay.settings,app.CallingApp.defaultSetOver);
                            app.CallingApp.overlay.settings{ns}.hemi = app.CallingApp.overlay.settings{si(1)}.hemi;
                            app.CallingApp.overlay.settings{ns}.limMinOver = min(d);
                            app.CallingApp.overlay.settings{ns}.limMaxOver = max(d);

                            app.CallingApp.modOverlaySelect.Items = app.CallingApp.overlayListBox.Items;
                            app.CallingApp.TwoD1.Items = app.CallingApp.overlayListBox.Items;
                            app.CallingApp.TwoD2.Items = app.CallingApp.overlayListBox.Items;

                            app.CallingApp.ThreeD1.Items = app.CallingApp.overlayListBox.Items;
                            app.CallingApp.ThreeD2.Items = app.CallingApp.overlayListBox.Items;
                            app.CallingApp.ThreeD3.Items = app.CallingApp.overlayListBox.Items;
                        if size(d,2) > 2
                            warning('Divided only the first two selected overlays')
                        end
                    else
                        uialert(app.brainSurferUIFigure,'You cannot divide from two different hemispheres','Action unavailable');
                    end

                case 'pca'
                    if numel(unique(hemi))==1
                        ns = length(app.CallingApp.overlay.bm) + 1;
                        nmTmp = app.CallingApp.overlay.names(si);
                        for i = 1:length(nmTmp)
                            [~, nm{i}, e] = fileparts(nmTmp(i));
                            if length(nm{i}) > 4
                                if strcmpi(nm{i}(end-3:end),'.nii') | strcmpi(nm{i}(end-3:end),'.gii')
                                    e = [nm{i}(end-3:end) e];
                                    nm{i} = nm{i}(1:end-4);
                                end
                            end
                            if i == length(nmTmp)
                                nm{i} = [nm{i} e];
                            end
                        end
                        nm = strjoin(app.CallingApp.overlay.names(si),'_CombinedWith_');

                        int = 1;
                        while int == 1
                            it = intersect(app.CallingApp.overlayListBox.Items,nm);
                            if ~isempty(it)
                                int = 1;
                                nm = [nm '_COPY_PCA'];
                            else
                                int = 0;
                            end
                        end

                        d = [];
                        for i = 1:length(si)
                            if ~isempty(app.CallingApp.overlay.settings{si(i)}.threshSmoothData)
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.settings{si(i)}.threshSmoothData];
                                else
                                    d = app.CallingApp.overlay.settings{si(i)}.threshSmoothData;
                                end
                            else
                                if i ~= 1
                                    d = [d app.CallingApp.overlay.bm{si(i)}];
                                else
                                    d = app.CallingApp.overlay.bm{si(i)};
                                end
                            end
                        end
                        %d = d(:,1)./d(:,2);
                        [~,d,~] = pca(d,'NumComponents',1);

                        app.CallingApp.overlayListBox.Items = [app.CallingApp.overlayListBox.Items  nm];
                        app.CallingApp.overlay.paths(ns) = {app.CallingApp.overlay.paths{si(1)}};
                        app.CallingApp.overlay.names(ns) = {nm};
                        app.CallingApp.overlay.bm{ns} = d;
                        app.CallingApp.overlay.bmB{ns} = d;
                        app.CallingApp.overlay.settings = horzcat(app.CallingApp.overlay.settings,app.CallingApp.defaultSetOver);
                        app.CallingApp.overlay.settings{ns}.hemi = app.CallingApp.overlay.settings{si(1)}.hemi;
                        app.CallingApp.overlay.settings{ns}.limMinOver = min(d);
                        app.CallingApp.overlay.settings{ns}.limMaxOver = max(d);

                        app.CallingApp.modOverlaySelect.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD2.Items = app.CallingApp.overlayListBox.Items;

                        app.CallingApp.ThreeD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD2.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD3.Items = app.CallingApp.overlayListBox.Items;
                    else
                        uialert(app.brainSurferUIFigure,'You cannot pca from two different hemispheres','Action unavailable');
                    end
                case 'delete'
                    if ~isempty(si)
                        app.CallingApp.overlayListBox.Items(si) = [];
                        app.CallingApp.overlay.names(si) = [];
                        app.CallingApp.overlay.paths(si) = [];
                        app.CallingApp.overlay.bm(si) = [];
                        app.CallingApp.overlay.bmB(si) = [];
                        app.CallingApp.overlay.settings(si) = [];
                        
                        s = setdiff(app.CallingApp.modOverlaySelect.Items,app.CallingApp.overlayListBox.Items);
                        for i = 2:length(app.CallingApp.overlay.settings)
                            for j = 1:length(s)
                                if strcmpi(app.CallingApp.overlay.settings{i}.modName,s{j})
                                    app.CallingApp.overlay.settings{i}.modData = [];
                                    app.CallingApp.overlay.settings{i}.modName = 'No overlay';
                                    app.CallingApp.overlay.settings{i}.absToggleMod = 'On';
                                    app.CallingApp.overlay.settings{i}.incZeroToggleMod = 'Off';
                                    app.CallingApp.overlay.settings{i}.minAlphaMod = 0;
                                    app.CallingApp.overlay.settings{i}.maxAlphaMod = 1;
                                end
                            end
                        end
                        app.CallingApp.modOverlaySelect.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.TwoD2.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD1.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD2.Items = app.CallingApp.overlayListBox.Items;
                        app.CallingApp.ThreeD3.Items = app.CallingApp.overlayListBox.Items;
                        
                    else
                        uialert(app.CallingApp.brainSurferUIFigure,'You need to select overlays to delete.','Action unavailable');
                    end
                case 'copy'
                    if ~isempty(si)
                        [c,ia,~] = intersect(app.CallingApp.overlayListBox.Items,app.CallingApp.overlayListBox.Value);
                        for i = 1:length(si)
                            hemSave = app.CallingApp.overlay.settings{si(i)}.hemi;
                            tv = app.CallingApp.overlay.settings{si(i)}.threshVals;
                            tsd = app.CallingApp.overlay.settings{si(i)}.threshSmoothData;
                            clst = app.CallingApp.overlay.settings{si(i)}.clusters;
                            mam = app.CallingApp.overlay.settings{si(i)}.minAlphaMod;
                            mam2 = app.CallingApp.overlay.settings{si(i)}.maxAlphaMod;
                            aml = app.CallingApp.overlay.settings{si(i)}.alphaModLims;
                            td1n = app.CallingApp.overlay.settings{si(i)}.TwoDim1Name;
                            td2n = app.CallingApp.overlay.settings{si(i)}.TwoDim2Name;
                            thd1n = app.CallingApp.overlay.settings{si(i)}.ThreeDim1Name;
                            thd2n = app.CallingApp.overlay.settings{si(i)}.ThreeDim2Name;
                            thd3n = app.CallingApp.overlay.settings{si(i)}.ThreeDim3Name;
                            mcm = app.CallingApp.overlay.settings{si(i)}.multiCmap;
                            mct = app.CallingApp.overlay.settings{si(i)}.multiCmapTicks;
                            snd = app.CallingApp.overlay.settings{si(i)}.scndCbarAxis;
                            mdcm = app.CallingApp.overlay.settings{si(i)}.multiDataColorMapped;
                            mcbd = app.CallingApp.overlay.settings{si(i)}.multiCbData;
                            
                            bThrLow = app.CallingApp.overlay.settings{si(i)}.lowerThreshBox;
                            bThrUp = app.CallingApp.overlay.settings{si(i)}.upperThreshBox;
                            bLimMin = app.CallingApp.overlay.settings{si(i)}.limMinOver;
                            bLimMax = app.CallingApp.overlay.settings{si(i)}.limMaxOver;
                            
                            bSmthButton = app.CallingApp.overlay.settings{si(i)}.smoothButton;
                            bSmthArea = app.CallingApp.overlay.settings{si(i)}.smoothArea;
                            bSmthSteps = app.CallingApp.overlay.settings{si(i)}.smoothSteps;
                            bSmthAssgn = app.CallingApp.overlay.settings{si(i)}.assignSwitch;
                            bPthresh = app.CallingApp.overlay.settings{si(i)}.pThresh;
                            bpMap = app.CallingApp.overlay.settings{si(i)}.pMap;
                            bpMapB = app.CallingApp.overlay.settings{si(i)}.pMapB;
                            
                            app.CallingApp.overlay.settings{si(i)} = app.CallingApp.overlay.settings{ia};
                            app.CallingApp.overlay.settings{si(i)}.hemi = hemSave;
                            app.CallingApp.overlay.settings{si(i)}.smoothButton = bSmthButton;
                            app.CallingApp.overlay.settings{si(i)}.smoothArea = bSmthArea;
                            app.CallingApp.overlay.settings{si(i)}.smoothSteps = bSmthSteps;
                            app.CallingApp.overlay.settings{si(i)}.assignSwitch = bSmthAssgn;
                            app.CallingApp.overlay.settings{si(i)}.pThresh = bPthresh;
                            app.CallingApp.overlay.settings{si(i)}.pMap = bpMap;
                            app.CallingApp.overlay.settings{si(i)}.pMapB = bpMapB;
                            
                            app.CallingApp.overlay.settings{si(i)}.threshVals = tv;
                            app.CallingApp.overlay.settings{si(i)}.threshSmoothData = tsd;
                            app.CallingApp.overlay.settings{si(i)}.clusters = clst;
                            
                            app.CallingApp.overlay.settings{si(i)}.minAlphaMod = mam;
                            app.CallingApp.overlay.settings{si(i)}.maxAlphaMod = mam2;
                            app.CallingApp.overlay.settings{si(i)}.alphaModLims = aml;
                            app.CallingApp.overlay.settings{si(i)}.TwoDim1Name = td1n;
                            app.CallingApp.overlay.settings{si(i)}.TwoDim2Name = td2n;
                            app.CallingApp.overlay.settings{si(i)}.ThreeDim1Name = thd1n;
                            app.CallingApp.overlay.settings{si(i)}.ThreeDim2Name = thd2n;
                            app.CallingApp.overlay.settings{si(i)}.ThreeDim3Name = thd3n;
                            app.CallingApp.overlay.settings{si(i)}.multiCmap = mcm;
                            app.CallingApp.overlay.settings{si(i)}.multiCmapTicks = mct;
                            app.CallingApp.overlay.settings{si(i)}.scndCbarAxis = snd;
                            app.CallingApp.overlay.settings{si(i)}.multiDataColorMapped = mdcm;
                            app.CallingApp.overlay.settings{si(i)}.multiCbData = mcbd;
                            
                            if app.CopythresholdsCheckBox.Value ~= 1
                                app.CallingApp.overlay.settings{si(i)}.lowerThreshBox = bThrLow;
                                app.CallingApp.overlay.settings{si(i)}.upperThreshBox = bThrUp;
                            end
                            if app.CopyLimitsCheckBox.Value ~= 1
                                app.CallingApp.overlay.settings{si(i)}.limMinOver = bLimMin;
                                app.CallingApp.overlay.settings{si(i)}.limMaxOver = bLimMax;
                            end
                        end
                    else
                        uialert(app.CallingApp.brainSurferUIFigure,'You need to select overlays to copy.','Action unavailable');
                    end
                case 'mask'
                    if ~isempty(si)
                        [c,ia,~] = intersect(app.CallingApp.overlayListBox.Items,app.CallingApp.overlayListBox.Value);
                        if ~isempty(app.CallingApp.overlay.settings{si}.threshSmoothData)
                            id = find(app.CallingApp.overlay.settings{ia}.threshSmoothData == 0);
                        else
                            id = find(app.CallingApp.overlay.bm{ia} == 0);
                        end
                        for i = 1:length(si)
                            if length(app.CallingApp.overlay.bm{ia}) == length(app.CallingApp.overlay.bm{si(i)})
                                if isequal(app.CallingApp.overlay.settings{ia}.hemi,app.CallingApp.overlay.settings{si(i)}.hemi)
                                    app.CallingApp.overlay.bm{si(i)}(id) = 0;
                                else
                                    uialert(app.CallingApp.brainSurferUIFigure,'You are trying to mask a file that is in a different hemisphere.','Action unavailable');
                                end
                            else
                                uialert(app.CallingApp.brainSurferUIFigure,'You are trying to mask a file that is in a different space.','Action unavailable');
                            end
                        end
                    else
                        uialert(app.CallingApp.brainSurferUIFigure,'You need to select overlays to mask by currently selected overlay.','Action unavailable');
                    end
                case 'inverse mask'
                    if ~isempty(si)
                        [c,ia,~] = intersect(app.CallingApp.overlayListBox.Items,app.CallingApp.overlayListBox.Value);
                        if ~isempty(app.CallingApp.overlay.settings{si}.threshSmoothData)
                            id = find(app.CallingApp.overlay.settings{ia}.threshSmoothData ~= 0);
                        else
                            id = find(app.CallingApp.overlay.bm{ia} ~= 0);
                        end
                        for i = 1:length(si)
                            if length(app.CallingApp.overlay.bm{ia}) == length(app.CallingApp.overlay.bm{si(i)})
                                if isequal(app.CallingApp.overlay.settings{ia}.hemi,app.CallingApp.overlay.settings{si(i)}.hemi)
                                    app.CallingApp.overlay.bm{si(i)}(id) = 0;
                                else
                                    uialert(app.CallingApp.brainSurferUIFigure,'You are trying to mask a file that is in a different hemisphere.','Action unavailable');
                                end
                            else
                                uialert(app.CallingApp.brainSurferUIFigure,'You are trying to mask a file that is in a different space.','Action unavailable');
                            end
                        end
                    else
                        uialert(app.CallingApp.brainSurferUIFigure,'You need to select overlays to mask by currently selected overlay.','Action unavailable');
                    end
                    
                case 'duplicate'
                    if ~isempty(si)
                        
                    else
                        uialert(app.CallingApp.brainSurferUIFigure,'You need to select overlays to mask by currently selected overlay.','Action unavailable');
                    end
                case 'reload'
                    if ~isempty(si)
                        [c,ia,~] = intersect(app.CallingApp.overlayListBox.Items,app.CallingApp.overlayListBox.Value);
                        for i = 1:length(si)
                            app.CallingApp.overlay.bm{si(i)} = app.CallingApp.overlay.bmB{si(i)};                            
                            tmp = app.CallingApp.overlay.settings{si(i)}.hemi;
                            
                            app.CallingApp.overlay.settings{si(i)} = app.CallingApp.defaultSetOver;
                            app.CallingApp.overlay.settings{si(i)}.hemi = tmp;
                            app.CallingApp.overlay.settings{si(i)}.limMinOver = min(app.CallingApp.overlay.bm{si(i)});
                            app.CallingApp.overlay.settings{si(i)}.limMaxOver = max(app.CallingApp.overlay.bm{si(i)});
                        end
                        if intersect(ia,si)
                            app.CallingApp = overPatch(app.CallingApp,'false','false','false');
                        end
                    else
                        uialert(app.CallingApp.brainSurferUIFigure,'You need to select overlays to reload.','Action unavailable');
                    end
                case 'screenshot'
                    if ~isempty(si)
                        for i = 1:length(si)
                            app.CallingApp.overlayListBox.Value = app.CallingApp.overlayListBox.Items(si(i));
                            [app.CallingApp] = overPatch(app.CallingApp,'false','false','true');
                            nm = app.CallingApp.overlay.names{si(i)};
                            pth = app.CallingApp.overlay.paths{si(i)};
                            [~, n, e] = fileparts(nm);
                            if strcmp(e,'.gz') & strcmp(n(end-3:end),'.nii')
                                n = n(1:end-4);
                                e = '.png';
                            else
                                e = '.png';
                            end
                            
                            ex = 1;
                            appen = '_copy_';
                            appnd = 0;
                            while ex == 1
                                if ~exist([pth n e],'file')
                                    ex=0;
                                else
                                    n = [n appen];
                                    appnd = 1;
                                end
                            end
                            if appnd == 1
                                n = n(1:end-1);
                            end
                            
                            tmp = findall(app.CallingApp.brainFig.Children,'Type','Axes');
                            if length(tmp) > 1
                                tmp=tmp(end);
                            end
                            
                            view(tmp,[-90 0]);
                            
                            if isfield(app.CallingApp.underlay,'left')
                                tmpO = [pth n '_leftLateralView' e];
                            else
                                tmpO = [pth n '_rightMedialView' e];
                            end
                            saveas(app.CallingApp.brainFig,tmpO);
                            if strcmpi(app.CallingApp.ci,'true')
                                cropImages({tmpO},'false',1)
                            end
                            
                            view(tmp,[90 0]);
                            if isfield(app.CallingApp.underlay,'right')
                                tmpO = [pth n '_rightLateralView' e];
                            else
                                tmpO = [pth n '_leftMedialView' e];
                            end
                            saveas(app.CallingApp.brainFig,tmpO);
                            if strcmpi(app.CallingApp.ci,'true')
                                cropImages({tmpO},'false',1)
                            end
                            
                            view(tmp,[90 -90]);
                            saveas(app.CallingApp.brainFig,[pth n '_inferiorView' e]);
                            if strcmpi(app.CallingApp.ci,'true')
                                cropImages({[pth n '_inferiorView' e]},'false',1)
                            end
                            
                            if isfield(app.CallingApp.underlay,'left')
                                for j = 1:length(app.CallingApp.currOver)
                                    if isfield(app.CallingApp.underlay,'right')
                                        tmpL = intersect(app.CallingApp.currOver{j}.Vertices,app.CallingApp.underlay.left.Vertices,'rows');
                                        if ~isempty(tmpL)
                                            app.CallingApp.currOver{j}.Visible = 'off';
                                        end
                                    end
                                end
                                app.CallingApp.underlay.left.Visible = 'off';
                                if ~isempty(app.CallingApp.atlas.overlay.left)
                                    app.CallingApp.atlas.overlay.left.Visible = 'off';
                                end
                                
                                view(tmp,[-90 0]);
                                saveas(app.CallingApp.brainFig,[pth n '_rightMedialView' e]);
                                if strcmpi(app.CallingApp.ci,'true')
                                    cropImages({[pth n '_rightMedialView' e]},'false',1)
                                end
                                for j = 1:length(app.CallingApp.currOver)
                                    if isfield(app.CallingApp.underlay,'right')
                                        tmpL = intersect(app.CallingApp.currOver{j}.Vertices,app.CallingApp.underlay.left.Vertices,'rows');
                                        if ~isempty(tmpL)
                                            app.CallingApp.currOver{j}.Visible = 'on';
                                        end
                                    end
                                end
                                app.CallingApp.underlay.left.Visible = 'on';
                                if ~isempty(app.CallingApp.atlas.overlay.left)
                                    app.CallingApp.atlas.overlay.left.Visible = 'on';
                                end
                            end
                            
                            if isfield(app.CallingApp.underlay,'right')
                                for j = 1:length(app.CallingApp.currOver)
                                    if isfield(app.CallingApp.underlay,'left')
                                        tmpL = intersect(app.CallingApp.currOver{j}.Vertices,app.CallingApp.underlay.right.Vertices,'rows');
                                        if ~isempty(tmpL)
                                            app.CallingApp.currOver{j}.Visible = 'off';
                                        end
                                    end
                                end
                                app.CallingApp.underlay.right.Visible = 'off';
                                if ~isempty(app.CallingApp.atlas.overlay.right)
                                    app.CallingApp.atlas.overlay.right.Visible = 'off';
                                end
                                
                                view(tmp,[90 0]);
                                saveas(app.CallingApp.brainFig,[pth n '_leftMedialView' e]);
                                if strcmpi(app.CallingApp.ci,'true')
                                    cropImages({[pth n '_leftMedialView' e]},'false',1)
                                end
                                for j = 1:length(app.CallingApp.currOver)
                                    if isfield(app.CallingApp.underlay,'left')
                                        tmpL = intersect(app.CallingApp.currOver{j}.Vertices,app.CallingApp.underlay.right.Vertices,'rows');
                                        if ~isempty(tmpL)
                                            app.CallingApp.currOver{j}.Visible = 'on';
                                        end
                                    end
                                end
                                app.CallingApp.underlay.right.Visible = 'on';
                                if ~isempty(app.CallingApp.atlas.overlay.right)
                                    app.CallingApp.atlas.overlay.right.Visible = 'on';
                                end
                            end
                        end
                        app.CallingApp.overlayListBox.Value = app.CallingApp.overlayListBox.Items(1);
                        [app.CallingApp] = overPatch(app.CallingApp,'false','false','true');
                    else
                        uialert(app.CallingApp.brainSurferUIFigure,'You need to select overlays to screenshot.','Action unavailable');
                    end
                    
                case 'save'
                    if ~isempty(si)                        
                        for i = 1:length(si)
                            [p,f,e] = fileparts([app.CallingApp.overlay.paths{si(i)} '/' app.CallingApp.overlay.names{si(i)}]);
                            if strcmp(f(end-2:end),'nii')
                                f=f(1:end-4);
                                e = ['.nii' e];
                            end
                            if strcmp(f(end-2:end),'gii')
                                f=f(1:end-4);
                                e = ['.gii' e];
                            end

                            pos = {'*.nii','Nifti (*.nii)';'*.gii','Gifti (*.gii)';'*.nii','CIFTI (*.nii)';'*.label','Label (*.label)';'*.annot','Annotation (*.annot)';'*.*','Morphometry file (*.*)'};
                            [oFile, oPath, indx] = uiputfile(pos,'Choose file name and extension',[p f]);

                            if isempty(app.CallingApp.overlay.settings{si(i)}.multiData)
                                if isempty(app.CallingApp.overlay.settings{si(i)}.threshSmoothData)
                                    d = app.CallingApp.overlay.bm{si(i)};
                                else
                                    d = app.CallingApp.overlay.settings{si(i)}.threshSmoothData;
                                end
                                
                                if indx == 1 % nifti
                                    template = load_nifti([app.CallingApp.pths.scrptPth app.CallingApp.pths.sep 'fileTemplate' app.CallingApp.pths.sep 'template.nii.gz']);
                                    template.vol = d;
                                    save_nifti(template,[oPath oFile])
                                elseif indx == 2 % gifti
                                    template = gifti([app.CallingApp.pths.scrptPth app.CallingApp.pths.sep 'fileTemplate' app.CallingApp.pths.sep 'template.gii']);
                                    template.cdata = d;
                                    save(template,[oPath oFile])
                                elseif indx == 3 % cifti
                                    if strcmpi(app.CallingApp.overlay.settings{si(i)}.hemi,'left')
                                        template = cifti_read([app.CallingApp.pths.scrptPth app.CallingApp.pths.sep 'fileTemplate' app.CallingApp.pths.sep 'template_L.nii']);
                                    elseif strcmpi(app.CallingApp.overlay.settings{si(i)}.hemi,'right')
                                        template = cifti_read([app.CallingApp.pths.scrptPth app.CallingApp.pths.sep 'fileTemplate' app.CallingApp.pths.sep 'template_R.nii']);
                                    end
                                    
                                    id = find(d ~= 0);
                                    template.cdata = d(id);
                                    template.diminfo{1}.models{1}.start = 1;
                                    template.diminfo{1}.models{1}.count = length(template.cdata);
                                    template.diminfo{1}.length = template.diminfo{1}.models{1}.count;
                                    template.diminfo{1}.models{1}.numvert = length(d);
                                    template.diminfo{1}.models{1}.vertlist = (id - 1)';
                                    
                                    if isinteger(d)
                                        un = unique(d);
                                        if ~isempty(app.CallingApp.overlay.settings{si(i)}.color)
                                            [colormapMapTmp,cDataTmp,colormapdataMapTmp,ticksTicksTmp,tickslabelsTmp] = colormapper(d,'colormap',app.CallingApp.overlay.settings{si(i)}.colormapSelector,'colorSpacing',app.CallingApp.overlay.settings{si(i)}.selectSpacing,'colorBins',app.CallingApp.overlay.settings{si(i)}.binBox,'colorSpecial',app.CallingApp.overlay.settings{si(i)}.colorSpecial,'invertColors',app.CallingApp.overlay.settings{si(i)}.invertSwitch,'limits',[min([app.CallingApp.overlay.settings{si(i)}.limMinOver app.CallingApp.overlay.settings{si(i)}.limMaxOver]) max([app.CallingApp.overlay.settings{si(i)}.limMinOver app.CallingApp.overlay.settings{si(i)}.limMaxOver])],'thresh',[app.CallingApp.overlay.settings{si(i)}.lowerThreshBox app.CallingApp.overlay.settings{si(i)}.upperThreshBox]);
                                        end
                                        for j = 1:length(un)
                                            tbl(j).name = num2str(un(j));
                                            tbl(j).key = un(j);
                                            id = find(d == un(j));
                                            if ~isempty(app.CallingApp.overlay.settings{si(i)}.color)
                                                tbl(j).rgba = [cDataTmp(id(1),:)' ; 1];
                                            else
                                                tbl(j).rgba = [1; 1; 1; 1];
                                            end
                                        end
                                        template.diminfo{2}.maps.table = tbl;
                                        iti = '.dlabel';
                                    else
                                        template.diminfo{2}.maps.table = [];
                                        template.diminfo{2}.type = 'scalars';
                                        iti = '.dscalar';
                                    end
                                                                        
                                    cifti_write(template,[oPath oFile(1:end-4) iti '.nii'],'keepmetadata', false, 'disableprovenance',true)
                                elseif indx == 4 % label
                                    %[lTemplate, cTemplate] = read_label([],[app.CallingApp.pths.scrptPth app.CallingApp.pths.sep 'fileTemplate' app.CallingApp.pths.sep 'template.label']);
                                    l = d;
                                    id = find(l ~= 0);
                                    ok = write_label(id-1, app.CallingApp.underlay.(app.CallingApp.overlay.settings{si(i)}.hemi).Vertices(id,:), zeros(length(id),1), [oPath oFile]);
                                elseif indx == 5 % annot
                                    %[vTemplate, lTemplate, cTemplate] = read_annotation([app.CallingApp.pths.scrptPth app.CallingApp.pths.sep 'fileTemplate' app.CallingApp.pths.sep 'template.annot']);
                                    un = unique(d);
                                    cTemplate.numEntries = length(un);
                                    cTemplate.orig_tab = [p f e];
                                    cTemplate.struct_names = cell(cTemplate.numEntries,1);
                                    for j = 1:length(cTemplate.struct_names)
                                        cTemplate.struct_names{j} = num2str(un(j));
                                    end
                                    tbl(1:cTemplate.numEntries,4) = 0;
                                    tbl(1:cTemplate.numEntries,5) = str2double(cTemplate.struct_names);

                                    if ~isempty(app.CallingApp.overlay.settings{si(i)}.color)
                                        [colormapMapTmp,cDataTmp,colormapdataMapTmp,ticksTicksTmp,tickslabelsTmp] = colormapper(d,'colormap',app.CallingApp.overlay.settings{si(i)}.colormapSelector,'colorSpacing',app.CallingApp.overlay.settings{si(i)}.selectSpacing,'colorBins',app.CallingApp.overlay.settings{si(i)}.binBox,'colorSpecial',app.CallingApp.overlay.settings{si(i)}.colorSpecial,'invertColors',app.CallingApp.overlay.settings{si(i)}.invertSwitch,'limits',[min([app.CallingApp.overlay.settings{si(i)}.limMinOver app.CallingApp.overlay.settings{si(i)}.limMaxOver]) max([app.CallingApp.overlay.settings{si(i)}.limMinOver app.CallingApp.overlay.settings{si(i)}.limMaxOver])],'thresh',[app.CallingApp.overlay.settings{si(i)}.lowerThreshBox app.CallingApp.overlay.settings{si(i)}.upperThreshBox]);
                                        clrs = [];
                                        for j = 1:cTemplate.numEntries
                                            id = find(d == un(j));
                                            clrs = [clrs; cDataTmp(id(1),:)];
                                        end
                                        clrs = round(clrs.*256);
                                    else
                                        clrs = ones(cTemplate.numEntries,3)+255;
                                    end

                                    % adjust rgb range to start from 0
                                    tid = find(clrs ~= 0);
                                    clrs(tid) = clrs(tid)-1;

                                    tbl(:,1:3) = clrs;
                                    id = find(tbl(:,end) == 0);
                                    tbl(id,1) = 255; tbl(id,2) = 255; tbl(id,3) = 255;
                                    

                                    for j = 1:cTemplate.numEntries
                                        newVals(j,1) = tbl(j,1) + tbl(j,2)*2^8 + tbl(j,3)*2^16;
                                    end
                                    tbl(:,end) = newVals;
                                    [~, is2] = ismember(d,un);
                                    d = newVals(is2);
                                    cTemplate.table = tbl;
                                    write_annotation([oPath oFile], [(1:length(d)) - 1]',d, cTemplate)
                                    
                                elseif indx == 6 % morphology file
                                    write_curv([oPath oFile], d, size(app.CallingApp.underlay.(app.CallingApp.overlay.settings{si(i)}.hemi).Faces,1));
                                end
                                    
                            else
                                uialert(app.brainSurferUIFigure,'You cannot save a complex overlay...','Action unavailable');
                            end
                        end
                    else
                        uialert(app.CallingApp.brainSurferUIFigure,'You need to select overlays to screenshot.','Action unavailable');
                    end
            end
            
            for i = 1:length(app.props)
                try
                    app.CallingApp.(app.props{i}).Enable = 'on';
                catch
                end
            end
            app.CallingApp.lOver.Visible = 'on';
            app.CallingApp.lSurf.Visible = 'on';
            delete(app)
        end

        % Close request function: UIFigure
        function oSelectCloseRequest(app, event)
            for i = 1:length(app.props)
                try
                    app.CallingApp.(app.props{i}).Enable = 'on';
                catch
                end
            end
            app.CallingApp.lOver.Visible = 'on';
            app.CallingApp.lSurf.Visible = 'on';
            delete(app)
        end

        % Value changed function: CopythresholdsCheckBox
        function copyThresholdsTick(app, event)
%value = app.CopythresholdsCheckBox.Value;

        end

        % Value changed function: CopyLimitsCheckBox
        function copyLimitsTick(app, event)
%value = app.CopyLimitsCheckBox.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @oSelectCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Select overlays';
            app.overlayListBox = uilistbox(app.UIFigure);
            app.overlayListBox.Items = {'No overlay'};
            app.overlayListBox.Multiselect = 'on';
            app.overlayListBox.Value = {'No overlay'};
            app.MakeSelectionButton = uibutton(app.UIFigure, 'push');
            app.MakeSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @makeSelection, true);
            app.MakeSelectionButton.Text = 'Make Selection';
            app.CopythresholdsCheckBox = uicheckbox(app.UIFigure);
            app.CopythresholdsCheckBox.ValueChangedFcn = createCallbackFcn(app, @copyThresholdsTick, true);
            app.CopythresholdsCheckBox.Text = 'Copy thresholds';
            app.CopyLimitsCheckBox = uicheckbox(app.UIFigure);
            app.CopyLimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @copyLimitsTick, true);
            app.CopyLimitsCheckBox.Text = 'Copy Limits';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oSelect(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end