classdef outProp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ButtonGroup                  matlab.ui.container.ButtonGroup
        DropDown                     matlab.ui.control.DropDown
        EdgeAlphaSpinner             matlab.ui.control.Spinner
        EdgeAlphaSpinnerLabel        matlab.ui.control.Label
        EdgecolorButtonGroup         matlab.ui.container.ButtonGroup
        InterpButton                 matlab.ui.control.ToggleButton
        FlatButton                   matlab.ui.control.ToggleButton
        NoneButton                   matlab.ui.control.ToggleButton
        OutlinepropertiesatlasLabel  matlab.ui.control.Label
        LineStyleButtonGroup         matlab.ui.container.ButtonGroup
        DashdottedButton             matlab.ui.control.ToggleButton
        DottedButton                 matlab.ui.control.ToggleButton
        DashedButton                 matlab.ui.control.ToggleButton
        SolidButton                  matlab.ui.control.ToggleButton
        Panel                        matlab.ui.container.Panel
        LineWidthSpinner             matlab.ui.control.Spinner
        LineWidthSpinnerLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    
    methods (Access = private)
  

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % get main app datastartupFcn2(app);
            app.CallingApp = mainapp;

            % turn off main app
            props = properties(app.CallingApp);
            for i = 1:length(props)
                try
                    app.CallingApp.(props{i}).Enable = 'off';
                catch
                end
            end
            app.CallingApp.lOver.Visible = 'off';
            app.CallingApp.lSurf.Visible = 'off';

            if isa(app.CallingApp.lpropa.ea,'char')
                app.EdgeAlphaSpinner.Value = 0;
                switch lower(app.CallingApp.lpropa.ec)
                    case 'none'
                        app.DropDown.Value = app.DropDown.Items{1};
                    case 'flat'
                        app.DropDown.Value = app.DropDown.Items{2};
                    case 'interp'
                        app.DropDown.Value = app.DropDown.Items{3};
                end
            else
                app.DropDown.Value = app.DropDown.Items{1};
                app.EdgeAlphaSpinner.Value = app.CallingApp.lpropa.ea;
            end

            app.LineWidthSpinner.Value = app.CallingApp.lpropa.lw;

            switch lower(app.CallingApp.lpropa.ec)
                case 'none'
                    app.EdgecolorButtonGroup.Buttons(1).Value = true;
                    %app.LineStyleButtonGroup.Buttons(1)
                case 'flat'
                    app.EdgecolorButtonGroup.Buttons(2).Value = true;
                case 'interp'
                    app.EdgecolorButtonGroup.Buttons(3).Value = true;
            end

            switch lower(app.CallingApp.lpropa.ls)
                case '-'
                    app.LineStyleButtonGroup.Buttons(1).Value = true;
                case '--'
                    app.LineStyleButtonGroup.Buttons(2).Value = true;
                case ':'
                    app.LineStyleButtonGroup.Buttons(3).Value = true;
                case '-.'
                    app.LineStyleButtonGroup.Buttons(4).Value = true;
            end
        end

        % Selection changed function: LineStyleButtonGroup
        function lineStyleChange(app, event)
            switch lower(app.LineStyleButtonGroup.SelectedObject.Text)
                case 'solid'
                    ls = '-';
                case 'dashed'
                    ls = '--';
                case 'dotted'
                    ls = ':';
                case 'dash-dotted'
                    ls = '-.';
            end
            app.CallingApp.lpropa.ls = ls;
            for i = 1:length(app.CallingApp.currOver)
                app.CallingApp.atlas.overlay.left.LineStyle = app.CallingApp.lpropa.ls;
                app.CallingApp.atlas.overlay.right.LineStyle = app.CallingApp.lpropa.ls;
            end
        end

        % Selection changed function: EdgecolorButtonGroup
        function edgeColorChange(app, event)
            app.CallingApp.lpropa.ec = lower(app.EdgecolorButtonGroup.SelectedObject.Text);
            for i = 1:length(app.CallingApp.currOver)
                app.CallingApp.atlas.overlay.left.EdgeColor = app.CallingApp.lpropa.ec;
                app.CallingApp.atlas.overlay.right.EdgeColor = app.CallingApp.lpropa.ec;
            end
        end

        % Value changed function: LineWidthSpinner
        function lineWidthChange(app, event)
            app.CallingApp.lpropa.lw = app.LineWidthSpinner.Value;
            for i = 1:length(app.CallingApp.currOver)
                app.CallingApp.atlas.overlay.left.LineWidth = app.CallingApp.lpropa.lw;
                app.CallingApp.atlas.overlay.right.LineWidth = app.CallingApp.lpropa.lw;
            end
        end

        % Value changed function: EdgeAlphaSpinner
        function edgeAlphaChange(app, event)
            app.CallingApp.lpropa.ea = app.EdgeAlphaSpinner.Value;
            for i = 1:length(app.CallingApp.currOver)
                app.CallingApp.atlas.overlay.left.EdgeAlpha = app.CallingApp.lpropa.ea;
                app.CallingApp.atlas.overlay.right.EdgeAlpha = app.CallingApp.lpropa.ea;
            end
            app.DropDown.Value = app.DropDown.Items{1};
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % turn off main app
            props = properties(app.CallingApp);
            for i = 1:length(props)
                try
                    app.CallingApp.(props{i}).Enable = 'on';
                catch
                end
            end
            app.CallingApp.lOver.Visible = 'on';
            app.CallingApp.lSurf.Visible = 'on';
            delete(app)
        end

        % Value changed function: DropDown
        function edgeAlphaChange2(app, event)
            app.CallingApp.lpropa.ea = lower(app.DropDown.Value);
            app.EdgeAlphaSpinner.Value = 0;
            if ~strcmpi(app.DropDown.Value,'none')
                for i = 1:length(app.CallingApp.currOver)
                    app.CallingApp.atlas.overlay.left.EdgeAlpha = app.CallingApp.lpropa.ea;
                    app.CallingApp.atlas.overlay.right.EdgeAlpha = app.CallingApp.lpropa.ea;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.LineWidthSpinnerLabel = uilabel(app.Panel);
            app.LineWidthSpinnerLabel.Text = 'Line Width';
            app.LineWidthSpinner = uispinner(app.Panel);
            app.LineWidthSpinner.Limits = [0.01 Inf];
            app.LineWidthSpinner.ValueChangedFcn = createCallbackFcn(app, @lineWidthChange, true);
            app.LineWidthSpinner.Value = 0.01;
            app.LineStyleButtonGroup = uibuttongroup(app.UIFigure);
            app.LineStyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @lineStyleChange, true);
            app.LineStyleButtonGroup.Title = 'Line Style';
            app.SolidButton = uitogglebutton(app.LineStyleButtonGroup);
            app.SolidButton.Text = 'Solid';
            app.SolidButton.Value = true;
            app.DashedButton = uitogglebutton(app.LineStyleButtonGroup);
            app.DashedButton.Text = 'Dashed';
            app.DottedButton = uitogglebutton(app.LineStyleButtonGroup);
            app.DottedButton.Text = 'Dotted';
            app.DashdottedButton = uitogglebutton(app.LineStyleButtonGroup);
            app.DashdottedButton.Text = 'Dash-dotted';
            app.OutlinepropertiesatlasLabel = uilabel(app.UIFigure);
            app.OutlinepropertiesatlasLabel.Text = 'Outline properties (atlas)';
            app.EdgecolorButtonGroup = uibuttongroup(app.UIFigure);
            app.EdgecolorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @edgeColorChange, true);
            app.EdgecolorButtonGroup.Title = 'Edge color';
            app.NoneButton = uitogglebutton(app.EdgecolorButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.FlatButton = uitogglebutton(app.EdgecolorButtonGroup);
            app.FlatButton.Text = 'Flat';
            app.InterpButton = uitogglebutton(app.EdgecolorButtonGroup);
            app.InterpButton.Text = 'Interp';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.EdgeAlphaSpinnerLabel = uilabel(app.ButtonGroup);
            app.EdgeAlphaSpinnerLabel.Text = 'Edge Alpha';
            app.EdgeAlphaSpinner = uispinner(app.ButtonGroup);
            app.EdgeAlphaSpinner.Step = 0.1;
            app.EdgeAlphaSpinner.Limits = [0 1];
            app.EdgeAlphaSpinner.ValueChangedFcn = createCallbackFcn(app, @edgeAlphaChange, true);
            app.DropDown = uidropdown(app.ButtonGroup);
            app.DropDown.Items = {'None', 'Flat', 'Interp'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @edgeAlphaChange2, true);
            app.DropDown.Value = 'None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = outProp2(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end