classdef oSelect < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label                   matlab.ui.control.Label
        overlayListBox          matlab.ui.control.ListBox
        MakeSelectionButton     matlab.ui.control.Button
        CopythresholdsCheckBox  matlab.ui.control.CheckBox
        CopyLimitsCheckBox      matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        %os = []; % Description
        CallingApp
    end
    
    properties (Access = private)
        actionType = []; % Description
        props = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, labelAdd)
            app.CallingApp = mainapp;
            app.overlayListBox.Items = app.CallingApp.overlayListBox.Items;
            app.Label.Text = [app.Label.Text labelAdd];
            
            tmp1 = strfind(app.Label.Text,'delete');
            tmp2 = strfind(app.Label.Text,'copy');
            tmp3 = strfind(app.Label.Text,'duplicate');
            tmp4 = strfind(app.Label.Text,'save');
            tmp5 = strfind(app.Label.Text,'screenshot');
            tmp6 = strfind(app.Label.Text,'mask');
            tmp7 = strfind(app.Label.Text,'reload');
            
            if ~isempty(tmp1)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'delete';
            end
            if ~isempty(tmp2)
                app.actionType = 'copy';
            end
            if ~isempty(tmp3)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'duplicate';
            end
            if ~isempty(tmp4)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'save';
            end
            if ~isempty(tmp5)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'screenshot';
            end
            if ~isempty(tmp6)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'mask';
            end
            if ~isempty(tmp7)
                app.CopythresholdsCheckBox.Visible = 'off';
                app.CopyLimitsCheckBox.Visible = 'off';
                app.actionType = 'reload';
            end
            app.props = properties(app.CallingApp);
            for i = 1:length(app.props)
                try
                    app.CallingApp.(app.props{i}).Enable = 'off';
                catch
                end
            end
            app.CallingApp.lOver.Visible = 'off';
            app.CallingApp.lSurf.Visible = 'off';
        end

        % Button pushed function: MakeSelectionButton
        function makeSelection(app, event)
            si = [];
            s = [];
            for i = 1:length(app.overlayListBox.Value)
                [st,~,sit] = intersect(app.overlayListBox.Value(i),app.overlayListBox.Items);
                si = [si; sit];
                s = [s; st];
            end
            id = find(si == 1);
            si(id) = [];
            
            switch app.actionType
                case 'delete'
                    if ~isempty(si)
                        app.CallingApp.overlayListBox.Items(si) = [];
                        app.CallingApp.overlay.names(si) = [];
                        app.CallingApp.overlay.paths(si) = [];
                        app.CallingApp.overlay.bm(si) = [];
                        app.CallingApp.overlay.bmB(si) = [];
                        app.CallingApp.overlay.settings(si) = [];
                    else
                        uialert(app.CallingApp.brainSurferUIFigure,'You need to select overlays to delete.','Action unavailable');
                    end
                case 'copy'
                    if ~isempty(si)
                        [c,ia,~] = intersect(app.CallingApp.overlayListBox.Items,app.CallingApp.overlayListBox.Value);
                        for i = 1:length(si)
                            bThrLow = app.CallingApp.overlay.settings{si(i)}.lowerThreshBox;
                            bThrUp = app.CallingApp.overlay.settings{si(i)}.upperThreshBox;
                            bLimMin = app.CallingApp.overlay.settings{si(i)}.limMinOver;
                            bLimMax = app.CallingApp.overlay.settings{si(i)}.limMaxOver;
                            
                            bSmthButton = app.CallingApp.overlay.settings{si(i)}.smoothButton;
                            bSmthArea = app.CallingApp.overlay.settings{si(i)}.smoothArea;
                            bSmthSteps = app.CallingApp.overlay.settings{si(i)}.smoothSteps;
                            bSmthAssgn = app.CallingApp.overlay.settings{si(i)}.assignSwitch;
                            bPthresh = app.CallingApp.overlay.settings{si(i)}.pThresh;
                            bpMap = app.CallingApp.overlay.settings{si(i)}.pMap;
                            bpMapB = app.CallingApp.overlay.settings{si(i)}.pMapB;
                            
                            app.CallingApp.overlay.settings{si(i)} = app.CallingApp.overlay.settings{ia};
                            
                            app.CallingApp.overlay.settings{si(i)}.smoothButton = bSmthButton;
                            app.CallingApp.overlay.settings{si(i)}.smoothArea = bSmthArea;
                            app.CallingApp.overlay.settings{si(i)}.smoothSteps = bSmthSteps;
                            app.CallingApp.overlay.settings{si(i)}.assignSwitch = bSmthAssgn;
                            app.CallingApp.overlay.settings{si(i)}.pThresh = bPthresh;
                            app.CallingApp.overlay.settings{si(i)}.pMap = bpMap;
                            app.CallingApp.overlay.settings{si(i)}.pMapB = bpMapB;
                            
                            if app.CopythresholdsCheckBox.Value ~= 1
                                app.CallingApp.overlay.settings{si(i)}.lowerThreshBox = bThrLow;
                                app.CallingApp.overlay.settings{si(i)}.upperThreshBox = bThrUp;
                            end
                            if app.CopyLimitsCheckBox.Value ~= 1
                                app.CallingApp.overlay.settings{si(i)}.limMinOver = bLimMin;
                                app.CallingApp.overlay.settings{si(i)}.limMaxOver = bLimMax;
                            end
                        end
                    else
                        uialert(app.CallingApp.brainSurferUIFigure,'You need to select overlays to copy.','Action unavailable');
                    end
                case 'mask'
                    if ~isempty(si)
                        [c,ia,~] = intersect(app.CallingApp.overlayListBox.Items,app.CallingApp.overlayListBox.Value);
                        id = find(app.CallingApp.overlay.bm{ia} == 0);
                        for i = 1:length(si)
                            if length(app.CallingApp.overlay.bm{ia}) == length(app.CallingApp.overlay.bm{si(i)})
                                if isequal(app.CallingApp.overlay.settings{ia}.hemi,app.CallingApp.overlay.settings{si(i)}.hemi)
                                    app.CallingApp.overlay.bm{si(i)}(id) = 0;
                                else
                                    uialert(app.CallingApp.brainSurferUIFigure,'You are trying to mask a file that is in a different hemisphere.','Action unavailable');
                                end
                            else
                                uialert(app.CallingApp.brainSurferUIFigure,'You are trying to mask a file that is in a different space.','Action unavailable');
                            end
                        end
                    else
                        uialert(app.CallingApp.brainSurferUIFigure,'You need to select overlays to mask by currently selected overlay.','Action unavailable');
                    end
                case 'duplicate'
                    if ~isempty(si)
                        
                    else
                        uialert(app.CallingApp.brainSurferUIFigure,'You need to select overlays to mask by currently selected overlay.','Action unavailable');
                    end
                case 'reload'
                    if ~isempty(si)
                        [c,ia,~] = intersect(app.CallingApp.overlayListBox.Items,app.CallingApp.overlayListBox.Value);
                        for i = 1:length(si)
                            app.CallingApp.overlay.bm{si(i)} = app.CallingApp.overlay.bmB{si(i)};                            
                            tmp = app.CallingApp.overlay.settings{si(i)}.hemi;
                            
                            app.CallingApp.overlay.settings{si(i)} = app.CallingApp.defaultSetOver;
                            app.CallingApp.overlay.settings{si(i)}.hemi = tmp;
                            app.CallingApp.overlay.settings{si(i)}.limMinOver = min(app.CallingApp.overlay.bm{si(i)});
                            app.CallingApp.overlay.settings{si(i)}.limMaxOver = max(app.CallingApp.overlay.bm{si(i)});
                        end
                        if intersect(ia,si)
                            app.CallingApp = overPatch(app.CallingApp,'false','false','false');
                        end
                    else
                        uialert(app.CallingApp.brainSurferUIFigure,'You need to select overlays to reload.','Action unavailable');
                    end
                case 'screenshot'
                    if ~isempty(si)
                        for i = 1:length(si)
                            app.CallingApp.overlayListBox.Value = app.CallingApp.overlayListBox.Items(si(i));
                            [app.CallingApp] = overPatch(app.CallingApp,'false','false','true');
                            nm = app.CallingApp.overlay.names{si(i)};
                            pth = app.CallingApp.overlay.paths{si(i)};
                            [~, n, e] = fileparts(nm);
                            if strcmp(e,'.gz') & strcmp(n(end-3:end),'.nii')
                                n = n(1:end-4);
                                e = '.png';
                            else
                                e = '.png';
                            end
                            
                            ex = 1;
                            appen = '_copy_';
                            appnd = 0;
                            while ex == 1
                                if ~exist([pth n e],'file')
                                    ex=0;
                                else
                                    n = [n appen];
                                    appnd = 1;
                                end
                            end
                            if appnd == 1
                                n = n(1:end-1);
                            end
                            
                            tmp = findall(app.CallingApp.brainFig.Children,'Type','Axes');
                            if length(tmp) > 1
                                tmp=tmp(end);
                            end
                            
                            view(tmp,[-90 0]);
                            
                            if isfield(app.CallingApp.underlay,'left')
                                tmpO = [pth n '_leftLateralView' e];
                            else
                                tmpO = [pth n '_rightMedialView' e];
                            end
                            saveas(app.CallingApp.brainFig,tmpO);
                            if strcmpi(app.CallingApp.ci,'true')
                                cropImages({tmpO},'false',1)
                            end
                            
                            view(tmp,[90 0]);
                            if isfield(app.CallingApp.underlay,'right')
                                tmpO = [pth n '_rightLateralView' e];
                            else
                                tmpO = [pth n '_leftMedialView' e];
                            end
                            saveas(app.CallingApp.brainFig,tmpO);
                            if strcmpi(app.CallingApp.ci,'true')
                                cropImages({tmpO},'false',1)
                            end
                            
                            view(tmp,[90 -90]);
                            saveas(app.CallingApp.brainFig,[pth n '_inferiorView' e]);
                            if strcmpi(app.CallingApp.ci,'true')
                                cropImages({[pth n '_inferiorView' e]},'false',1)
                            end
                            
                            if isfield(app.CallingApp.underlay,'left')
                                for j = 1:length(app.CallingApp.currOver)
                                    if isfield(app.CallingApp.underlay,'right')
                                        tmpL = intersect(app.CallingApp.currOver{j}.Vertices,app.CallingApp.underlay.left.Vertices,'rows');
                                        if ~isempty(tmpL)
                                            app.CallingApp.currOver{j}.Visible = 'off';
                                        end
                                    end
                                end
                                app.CallingApp.underlay.left.Visible = 'off';
                                view(tmp,[-90 0]);
                                saveas(app.CallingApp.brainFig,[pth n '_rightMedialView' e]);
                                if strcmpi(app.CallingApp.ci,'true')
                                    cropImages({[pth n '_rightMedialView' e]},'false',1)
                                end
                                for j = 1:length(app.CallingApp.currOver)
                                    if isfield(app.CallingApp.underlay,'right')
                                        tmpL = intersect(app.CallingApp.currOver{j}.Vertices,app.CallingApp.underlay.left.Vertices,'rows');
                                        if ~isempty(tmpL)
                                            app.CallingApp.currOver{j}.Visible = 'on';
                                        end
                                    end
                                end
                                app.CallingApp.underlay.left.Visible = 'on';
                            end
                            
                            if isfield(app.CallingApp.underlay,'right')
                                for j = 1:length(app.CallingApp.currOver)
                                    if isfield(app.CallingApp.underlay,'left')
                                        tmpL = intersect(app.CallingApp.currOver{j}.Vertices,app.CallingApp.underlay.right.Vertices,'rows');
                                        if ~isempty(tmpL)
                                            app.CallingApp.currOver{j}.Visible = 'off';
                                        end
                                    end
                                end
                                app.CallingApp.underlay.right.Visible = 'off';
                                
                                view(tmp,[90 0]);
                                saveas(app.CallingApp.brainFig,[pth n '_leftMedialView' e]);
                                if strcmpi(app.CallingApp.ci,'true')
                                    cropImages({[pth n '_leftMedialView' e]},'false',1)
                                end
                                for j = 1:length(app.CallingApp.currOver)
                                    if isfield(app.CallingApp.underlay,'left')
                                        tmpL = intersect(app.CallingApp.currOver{j}.Vertices,app.CallingApp.underlay.right.Vertices,'rows');
                                        if ~isempty(tmpL)
                                            app.CallingApp.currOver{j}.Visible = 'on';
                                        end
                                    end
                                end
                                app.CallingApp.underlay.right.Visible = 'on';
                            end
                        end
                        app.CallingApp.overlayListBox.Value = app.CallingApp.overlayListBox.Items(1);
                        [app.CallingApp] = overPatch(app.CallingApp,'false','false','true');
                    else
                        uialert(app.CallingApp.brainSurferUIFigure,'You need to select overlays to screenshot.','Action unavailable');
                    end
                    
                case 'save'
                    if ~isempty(si)
                        for i = 1:length(si)
                            nm = [app.CallingApp.overlay.paths{si(i)} '/' app.CallingApp.overlay.names{si(i)}];
                            
                            % now load in template
                            err=0;
                            try % try to load a regular nifti file
                                disp('Attempting to load as conventional nifti file...')
                                ld = load_nifti(nm);
                                d = ld.vol;
                                d2 = [];
                                ft = 'nifti';
                            catch
                                disp('NIFTI LOAD FAILED!')
                                try % if that did not work, load a gifti file
                                    disp('Attempting to load as gifti file...')
                                    ld = gifti(nm);
                                    if isfield(ld,'cdata')
                                        d = ld.cdata;
                                        d2 = [];
                                    else
                                        d = [];
                                        d2 = [];
                                    end
                                    ft = 'gifti';
                                catch
                                    disp('GIFTI LOAD FAILED!')
                                    try % if gifti did not work, try cifti
                                        disp('Attempting to load as cifti file...')
                                        ld = cifti_read(nm);
                                        
                                        c = cell2mat(ld.diminfo{1}.models);
                                        c = {c.struct};
                                        
                                        l = find(contains(c,'CORTEX_LEFT'));
                                        r = find(contains(c,'CORTEX_RIGHT'));
                                        
                                        d = zeros([ld.diminfo{1}.models{1}.numvert,1]);
                                        d2 = zeros([ld.diminfo{1}.models{2}.numvert,1]);
                                        
                                        d(ld.diminfo{1}.models{l}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1);
                                        d2(ld.diminfo{1}.models{r}.vertlist + 1) = ld.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1);
                                        
                                        ft = 'cifti';
                                    catch
                                        disp('CIFTI LOAD FAILED!')
                                        disp('Uh-oh...your file could not be loaded as nifti, gifti, or cifti')
                                        err = 1;
                                    end
                                end
                            end
                            
                            if err == 0
                                % setup output file name
                                [p,f,e] = fileparts(nm);
                                
                                if strcmp(f(end-2:end),'nii')
                                    f=f(1:end-4);
                                    e = ['.nii' e];
                                end
                                
                                ex=1;
                                nm2 = [p f];
                                
                                while ex == 1
                                    if exist([nm2 e],'file')
                                        if strcmp(ft,'cifti')
                                            nm2 = [nm2 '_thresholded_' app.CallingApp.overlay.settings{si(i)}.hemi 'Hemi_'];
                                        else
                                            nm2 = [nm2 '_thresholded_'];
                                        end
                                    else
                                        ex = 0;
                                    end
                                end
                                
                                if strcmp(e,'.nii.gz')
                                    e = '.nii';
                                    gzSwitch = 'true';
                                else
                                    gzSwitch = 'false';
                                end
                                
                                [oFile, oPath] = uiputfile({e},['Save thresholded file: ' f],[nm2(1:end-1) e]);
                                
                                if strcmp(gzSwitch,'true')
                                    oFile = [oFile '.gz'];
                                end
                                
                                switch ft
                                    case 'nifti'
                                        ld.vol = app.CallingApp.overlay.settings{si(i)}.threshVals;
                                        save_nifti(ld,[oPath oFile])
                                    case 'gifti'
                                        ld.cdata = app.CallingApp.overlay.settings{si(i)}.threshVals;
                                        save(ld,[oPath oFile])
                                    case 'cifti'
                                        if strcmpi(app.CallingApp.overlay.settings{si(i)}.hemi,'left')
                                            ld.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1) = app.CallingApp.overlay.settings{si(i)}.threshVals(ld.diminfo{1}.models{l}.vertlist + 1);
                                            ld.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1) = 0;
                                        elseif strcmpi(app.CallingApp.overlay.settings{si(i)}.hemi,'right')
                                            ld.cdata(ld.diminfo{1}.models{r}.start:ld.diminfo{1}.models{r}.start+ld.diminfo{1}.models{r}.count-1) = app.CallingApp.overlay.settings{si(i)}.threshVals(ld.diminfo{1}.models{r}.vertlist + 1);
                                            ld.cdata(ld.diminfo{1}.models{l}.start:ld.diminfo{1}.models{l}.start+ld.diminfo{1}.models{l}.count-1) = 0;
                                        end
                                        cifti_write(ld,[oPath oFile]);
                                end
                            end
                        end
                    else
                        uialert(app.CallingApp.brainSurferUIFigure,'You need to select overlays to screenshot.','Action unavailable');
                    end
            end
            
            for i = 1:length(app.props)
                try
                    app.CallingApp.(app.props{i}).Enable = 'on';
                catch
                end
            end
            app.CallingApp.lOver.Visible = 'on';
            app.CallingApp.lSurf.Visible = 'on';
            delete(app)
        end

        % Close request function: UIFigure
        function oSelectCloseRequest(app, event)
delete(app)
        end

        % Value changed function: CopythresholdsCheckBox
        function copyThresholdsTick(app, event)
%value = app.CopythresholdsCheckBox.Value;

        end

        % Value changed function: CopyLimitsCheckBox
        function copyLimitsTick(app, event)
%value = app.CopyLimitsCheckBox.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @oSelectCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Select overlays';
            app.overlayListBox = uilistbox(app.UIFigure);
            app.overlayListBox.Items = {'No overlay'};
            app.overlayListBox.Multiselect = 'on';
            app.overlayListBox.Value = {'No overlay'};
            app.MakeSelectionButton = uibutton(app.UIFigure, 'push');
            app.MakeSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @makeSelection, true);
            app.MakeSelectionButton.Text = 'Make Selection';
            app.CopythresholdsCheckBox = uicheckbox(app.UIFigure);
            app.CopythresholdsCheckBox.ValueChangedFcn = createCallbackFcn(app, @copyThresholdsTick, true);
            app.CopythresholdsCheckBox.Text = 'Copy thresholds';
            app.CopyLimitsCheckBox = uicheckbox(app.UIFigure);
            app.CopyLimitsCheckBox.ValueChangedFcn = createCallbackFcn(app, @copyLimitsTick, true);
            app.CopyLimitsCheckBox.Text = 'Copy Limits';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oSelect(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end